classdef LoVEBASS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoVEBASSUIFigure                matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpensesionMenu                  matlab.ui.container.Menu
        SavesessionMenu                 matlab.ui.container.Menu
        ExportresultlistMenu            matlab.ui.container.Menu
        LanguageMenu                    matlab.ui.container.Menu
        EnglishMenu                     matlab.ui.container.Menu
        SpanishMenu                     matlab.ui.container.Menu
        SpeakerDBMenu                   matlab.ui.container.Menu
        SelectdatabaseMenu              matlab.ui.container.Menu
        ViewactualdatabaseMenu          matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        HelpSC4QB3Menu                  matlab.ui.container.Menu
        HelpforDriverselectionfromdatabaseMenu  matlab.ui.container.Menu
        HelpforDefinitionofparametersforcustomdriverdesignMenu  matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        ListSpeakers                    matlab.ui.container.Panel
        TableSpeakers                   matlab.ui.control.Table
        PlotButton                      matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        SC4QB3AlignmentcriterionTab     matlab.ui.container.Tab
        RunButton                       matlab.ui.control.Button
        ConstantsPanel                  matlab.ui.container.Panel
        Airdensitykgm3Label             matlab.ui.control.Label
        rhofield                        matlab.ui.control.NumericEditField
        SoundspeedmsLabel               matlab.ui.control.Label
        cfield                          matlab.ui.control.NumericEditField
        ConditionsforspeakerssearchPanel  matlab.ui.container.Panel
        SPL_maxMajorCheckBox            matlab.ui.control.CheckBox
        SPL_1WMajorCheckBox             matlab.ui.control.CheckBox
        f3MinorCheckBox                 matlab.ui.control.CheckBox
        VbMinorCheckBox                 matlab.ui.control.CheckBox
        XmaxMinorCheckBox               matlab.ui.control.CheckBox
        SdMinorCheckBox                 matlab.ui.control.CheckBox
        XmaxMajorCheckBox_2             matlab.ui.control.CheckBox
        SdMajorCheckBox_2               matlab.ui.control.CheckBox
        VbMajorCheckBox_2               matlab.ui.control.CheckBox
        f3MajorCheckBox_2               matlab.ui.control.CheckBox
        SPL_maxMinorCheckBox_2          matlab.ui.control.CheckBox
        SPL_1WMinorCheckBox_2           matlab.ui.control.CheckBox
        DesiredparametersPanel          matlab.ui.container.Panel
        SPL_1WdBLabel                   matlab.ui.control.Label
        SPL1WdBEditField                matlab.ui.control.NumericEditField
        SPL_maxdBLabel                  matlab.ui.control.Label
        SPLmaxEditFieldPot              matlab.ui.control.NumericEditField
        f3HzEditFieldLabel              matlab.ui.control.Label
        f3Field                         matlab.ui.control.NumericEditField
        VblitersLabel                   matlab.ui.control.Label
        VblitersEditField               matlab.ui.control.NumericEditField
        XmaxmmEditFieldLabel            matlab.ui.control.Label
        XmaxmmEditField                 matlab.ui.control.NumericEditField
        SizeinchesLabel_2               matlab.ui.control.Label
        SizeinchesEditField             matlab.ui.control.NumericEditField
        IndicateonlytheonesyouwantnotallarenecessaryLabel  matlab.ui.control.Label
        PestAlign                       matlab.ui.container.TabGroup
        GraphicresultsTab               matlab.ui.container.Tab
        plotXmax                        matlab.ui.control.UIAxes
        plotSPL                         matlab.ui.control.UIAxes
        ParameterresultsTab             matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        AlignmentvaluesPanel            matlab.ui.container.Panel
        TableValues                     matlab.ui.control.Table
        ParametersresultsPanel          matlab.ui.container.Panel
        ParamResults                    matlab.ui.control.Table
        InfoAlign                       matlab.ui.control.Label
        DriverselectionfromdatabaseTab  matlab.ui.container.Tab
        InputParametersPanel            matlab.ui.container.Panel
        VblitersEditField_2Label        matlab.ui.control.Label
        VblitersEditField_2             matlab.ui.control.NumericEditField
        LFboostdBEditFieldLabel         matlab.ui.control.Label
        LFboostdBEditField              matlab.ui.control.NumericEditField
        f3HzEditFieldLabel_2            matlab.ui.control.Label
        f3HzEditField                   matlab.ui.control.NumericEditField
        SPLmaxdBEditFieldLabel          matlab.ui.control.Label
        SPLmaxdBEditField               matlab.ui.control.NumericEditField
        SizeinchesLabel                 matlab.ui.control.Label
        SizeEditField                   matlab.ui.control.NumericEditField
        EBPEditFieldLabel               matlab.ui.control.Label
        EBPEditField                    matlab.ui.control.NumericEditField
        RippledBEditFieldLabel          matlab.ui.control.Label
        RippledBEditField               matlab.ui.control.NumericEditField
        QlleakageQfactorLabel           matlab.ui.control.Label
        QlEditField                     matlab.ui.control.NumericEditField
        maximumLabel                    matlab.ui.control.Label
        maximumLabel_2                  matlab.ui.control.Label
        maximumLabel_3                  matlab.ui.control.Label
        minimumLabel                    matlab.ui.control.Label
        minimumLabel_2                  matlab.ui.control.Label
        maximumLabel_4                  matlab.ui.control.Label
        minimumLabel_3                  matlab.ui.control.Label
        GoalperformanceoftheVentedEnclosureLabel  matlab.ui.control.Label
        DriverrestrictionsLabel         matlab.ui.control.Label
        RunButton_Criteria2             matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        MaximalSPLTab                   matlab.ui.container.Tab
        MaxSPLplotSPL                   matlab.ui.control.UIAxes
        MaxSPLplotXmax                  matlab.ui.control.UIAxes
        AdjustPanelMaxSPL               matlab.ui.container.Panel
        fbHzSpinnerLabel                matlab.ui.control.Label
        f_bMaxSPL                       matlab.ui.control.Spinner
        VblitersLabel_2                 matlab.ui.control.Label
        VbMaxSPL                        matlab.ui.control.Spinner
        SPLmaxdBLabel                   matlab.ui.control.Label
        SPLMaxSPL                       matlab.ui.control.Spinner
        ResetButtonMaxSPL               matlab.ui.control.Button
        Minimalf3Tab                    matlab.ui.container.Tab
        Minf3plotSPL                    matlab.ui.control.UIAxes
        Minf3plotXmax                   matlab.ui.control.UIAxes
        AdjustPanelMinf3                matlab.ui.container.Panel
        fbHzSpinnerLabel_2              matlab.ui.control.Label
        f_bMinf3                        matlab.ui.control.Spinner
        VblitersLabel_3                 matlab.ui.control.Label
        VbMinf3                         matlab.ui.control.Spinner
        SPLmaxdBLabel_2                 matlab.ui.control.Label
        SPLMinf3                        matlab.ui.control.Spinner
        ResetButtonMinf3                matlab.ui.control.Button
        MinimalVbTab                    matlab.ui.container.Tab
        MinVbplotSPL                    matlab.ui.control.UIAxes
        MinVbplotXmax                   matlab.ui.control.UIAxes
        AdjustPanelMaxSPL_3             matlab.ui.container.Panel
        fbHzSpinnerLabel_3              matlab.ui.control.Label
        f_bMinVb                        matlab.ui.control.Spinner
        VblitersLabel_4                 matlab.ui.control.Label
        VbMinVb                         matlab.ui.control.Spinner
        SPLmaxdBLabel_3                 matlab.ui.control.Label
        SPLMinVb                        matlab.ui.control.Spinner
        ResetButtonMinVb                matlab.ui.control.Button
        ShortestPortTab                 matlab.ui.container.Tab
        MinPortplotSPL                  matlab.ui.control.UIAxes
        MinPortplotXmax                 matlab.ui.control.UIAxes
        AdjustPanelMinPort              matlab.ui.container.Panel
        fbHzSpinnerLabel_4              matlab.ui.control.Label
        f_bMinPort                      matlab.ui.control.Spinner
        VblitersLabel_5                 matlab.ui.control.Label
        VbMinPort                       matlab.ui.control.Spinner
        SPLmaxdBLabel_4                 matlab.ui.control.Label
        SPLMinPort                      matlab.ui.control.Spinner
        ResetButtonMinPort              matlab.ui.control.Button
        ConstantsPanel_2                matlab.ui.container.Panel
        Airdensitykgm3Label_2           matlab.ui.control.Label
        rhofield_2                      matlab.ui.control.NumericEditField
        SoundspeedmsLabel_2             matlab.ui.control.Label
        cfield_2                        matlab.ui.control.NumericEditField
        ProcessedspeakersLabel          matlab.ui.control.Label
        BarraCarga                      matlab.ui.control.SemicircularGauge
        DefinitionofparametersforcustomdriverdesignTab  matlab.ui.container.Tab
        RequiredparametersPanel         matlab.ui.container.Panel
        VblitersLabel_6                 matlab.ui.control.Label
        VbCustom                        matlab.ui.control.NumericEditField
        f3HzLabel                       matlab.ui.control.Label
        f3Custom                        matlab.ui.control.NumericEditField
        SPLmaxdBLabel_5                 matlab.ui.control.Label
        SPLmaxCustom                    matlab.ui.control.NumericEditField
        SizeinchesEditField_3Label      matlab.ui.control.Label
        SizeCustom                      matlab.ui.control.NumericEditField
        QLLabel                         matlab.ui.control.Label
        QL_Custom                       matlab.ui.control.NumericEditField
        ReohmEditFieldLabel             matlab.ui.control.Label
        ReCustom                        matlab.ui.control.NumericEditField
        AlignmenttypeDropDownLabel      matlab.ui.control.Label
        AlignmenttypeDropDown           matlab.ui.control.DropDown
        AlignCoefCustom                 matlab.ui.control.NumericEditField
        AlignCoefCustomLabel            matlab.ui.control.Label
        ConstantsPanel_3                matlab.ui.container.Panel
        Airdensitykgm3Label_3           matlab.ui.control.Label
        rhofield_3                      matlab.ui.control.NumericEditField
        SoundspeedmsLabel_3             matlab.ui.control.Label
        cfield_3                        matlab.ui.control.NumericEditField
        plotXmaxCustom                  matlab.ui.control.UIAxes
        plotSPLCustom                   matlab.ui.control.UIAxes
        ParametersresultsCustom         matlab.ui.container.Panel
        ParamResultsCustom              matlab.ui.control.Table
        WarningsTextArea                matlab.ui.container.Panel
        WarningsTexto                   matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        % Variable qie indica si semuestran funciones ocultas o no
        research
        
        % Añade las carpetas del programa
        ruta = addpath(genpath('include'));
        
        % Variable que contiene la direccion y nombre de la base de datos
        BaseDatos = 'SpeakerDB Default.xlsx'; % Por defecto la que esta en la carpeta include
        
        % Variable que almacena que pestaña se esta visualizando, del 1 en adelante
        % visto de izquirda a derecha
        pestana
        
        % Variable para almacenar la base de datos
        TablaDB
        % Variable para almacenar el resultado de cada método
        % Alignment
        TablaAlignment
        TablaDBCurvas
        % Parameters
        TablaParameters
        TablaDBCurvParam
        % vector de frecuencias
        freq
        
        % Variable que almacena la posiciones del altavoz elegido en la tabla
        % (haciendo clic encima de la fila del altavoz)
        AltSelect
        
        % Variable que almacena el indice de los altavoces recomendados por el criterio
        % de parametros en la tabla.
        IndexRecom
        
        % Constantes
        rho
        c
        
    end
    
    methods (Access = private)
        
        function [TablaAlig,Qts_0,Curva,QL] = TableLoad(app,Qts,Vb,mode,type)
            % Puede obtenerse segun el punto de cruce (modo 1) o seleccionando
            % directamente el alineamiento
            switch mode
                % Punto de cruce
                case 1
                    % Segun el volumen se elige un tipo de tablas
                    if Vb < 20 % QL = 15
                        QL = 15;
                        % Carga de la tabla QB3
                        tablaTemp = dlmread('QB3_QL15.txt','\t',1,0);
                        [TablaAlig,Qts_0] = interpTable(app,tablaTemp);
                        Curva = 'QB3';
                        
                        % Si el valor de Qts introducido por el usuario es
                        % mayor al valor donde se cruzan h y q, se carga la tabla de SC4
                        if Qts > Qts_0
                            tablaTemp = dlmread('SC4_QL15.txt','\t',1,0);
                            [TablaAlig,Qts_0] = interpTable(app,tablaTemp);
                            Curva = 'C4';
                        end
                        
                    elseif Vb >= 20 && Vb <= 80 % QL = 7
                        QL = 7;
                        % Carga de la tabla QB3
                        tablaTemp = dlmread('QB3_QL7.txt','\t',1,0);
                        [TablaAlig,Qts_0] = interpTable(app,tablaTemp);
                        Curva = 'QB3';
                        
                        % Si el valor de Qts introducido por el usuario es
                        % mayor al valor donde se cruzan h y q, se carga la tabla de SC4
                        if Qts > Qts_0
                            tablaTemp = dlmread('SC4_QL7.txt','\t',1,0);
                            [TablaAlig,Qts_0] = interpTable(app,tablaTemp);
                            Curva = 'C4';
                        end
                        
                    else % QL = 3
                        QL = 3;
                        % Carga de la tabla QB3
                        tablaTemp = dlmread('QB3_QL3.txt','\t',1,0);
                        [TablaAlig,Qts_0] = interpTable(app,tablaTemp);
                        Curva = 'QB3';
                        
                        % Si el valor de Qts introducido por el usuario es
                        % mayor al valor donde se cruzan h y q, se carga la tabla de SC4
                        if Qts > Qts_0
                            tablaTemp = dlmread('SC4_QL3.txt','\t',1,0);
                            [TablaAlig,Qts_0] = interpTable(app,tablaTemp);
                            Curva = 'C4';
                        end
                        
                    end
                    
                    % Seleccion directa
                case 2
                    % Segun el volumen se elige un QL
                    if Vb < 20 % QL = 15
                        QL = 15;
                    elseif Vb >= 20 && Vb <= 80 % QL = 7
                        QL = 7;
                    else
                        QL = 3;
                    end
                    
                    % Segun el alineamiento elegido y el QL se obtiene la tabla
                    switch type
                        case 'C4 | SC4'
                            Nombre = ['SC4_QL',num2str(QL),'.txt'];
                            Curva = 'C4';
                        case 'QB3'
                            Nombre = ['QB3_QL',num2str(QL),'.txt'];
                            Curva = 'QB3';
                    end
                    
                    % Carga de la tabla
                    tablaTemp = dlmread(Nombre,'\t',1,0);
                    [TablaAlig,Qts_0] = interpTable(app,tablaTemp);
            end
            
        end
        
        
        function [tabla,Qts_corte] = interpTable(~,tablaTemp)
            % Se crea una copia de la tabla con 9 filas entre cada fila original para
            % interpolar
            tablaTemp2 = zeros(size(tablaTemp,1)*10-9,size(tablaTemp,2));
            a = 0;
            for i = 1:10:size(tablaTemp2,1)
                a = a + 1;
                tablaTemp2(i,:) = tablaTemp(a,:);
            end
            
            % Interpolacion de las filas añadidas
            X = ~all(tablaTemp2==0,2); % Filas con valor = 0
            tabla = interp1(find(X),tablaTemp2(X,:),1:size(tablaTemp2,1));
            
            % Obtener Qts donde se cortan h y q
            CortesInd = find(abs(tabla(:,2)-tabla(:,4))<=(0.001));
            if ~isempty(CortesInd)
                Qts_corte = tabla(CortesInd(1),1);
            else
                CortesInd = find(abs(tabla(:,2)-tabla(:,4))<=(0.01));
                Qts_corte = tabla(CortesInd(1),1);
            end
        end
        
        function plotCurvesTable(app,TablaAlig,Curva,Qts,QLl,Nombre)
            % Representacion gráfica
            
            cla(app.UIAxes) % Limpia la grafica por si habia algo anterior
            cla(app.UIAxes) % Limpia la grafica por si habia algo anterior
            
            alphaArray = TablaAlig(:,3);
            QtsArray = TablaAlig(:,1);
            hArray = TablaAlig(:,2);
            qArray = TablaAlig(:,4);
            
            yyaxis(app.UIAxes,'left')
            %% Qts frente a alpha
            cu(1) = semilogx(app.UIAxes,alphaArray,QtsArray);
            ylabel(app.UIAxes,'Q_{ts}')
            if strcmp(Curva,'QB3')
                text(app.UIAxes,1.4,TablaAlig(find(alphaArray<=1.4,1,'first'),1)*1.02,'\color{blue}Q_{ts}')
            elseif strcmp(Curva,'C4')
                text(app.UIAxes,0.4,TablaAlig(find(alphaArray<=0.4,1,'first'),1)*1.02,'\color{blue}Q_{ts}')
            end
            hold(app.UIAxes,'on')
            % Marcador en el valor de Qts elegido
            semilogx(app.UIAxes,TablaAlig(find(QtsArray<=Qts,1,'last'),3),Qts,...
                'ko','MarkerFaceColor','red','MarkerSize',5)
            % Linea recta y paralela al eje X
            X = [min(alphaArray),TablaAlig(find(QtsArray<=Qts,1,'last'),3)];
            semilogx(app.UIAxes,X,[Qts,Qts],'--k')
            
            yyaxis(app.UIAxes,'right')
            %% h frente a alpha
            cu(2) = semilogx(app.UIAxes,alphaArray,hArray);
            %% q frente a alpha
            cu(3) = semilogx(app.UIAxes,alphaArray,qArray);
            ylabel(app.UIAxes,'h ||| q')
            % Marcadores en las curvas de h y q segun el valor de Qts elegido
            semilogx(app.UIAxes,TablaAlig(find(QtsArray<=Qts,1,'last'),3),TablaAlig(find(QtsArray<=Qts,1,'last'),2),...
                'ko','MarkerFaceColor','red','MarkerSize',5)
            semilogx(app.UIAxes,TablaAlig(find(QtsArray<=Qts,1,'last'),3),TablaAlig(find(QtsArray<=Qts,1,'last'),4),...
                'ko','MarkerFaceColor','red','MarkerSize',5)
            
            
            if strcmp(Curva,'QB3')
                app.UIAxes.XTick = [0.2,0.5,1,2,5,10];
                xlim(app.UIAxes,[0.2,inf])%,ylim(app.UIAxes,[0.5,3.5])
                text(app.UIAxes,7,TablaAlig(find(alphaArray<=7,1,'first'),2)*0.95,'\color{orange}h')
                text(app.UIAxes,7,TablaAlig(find(alphaArray<=7,1,'first'),4)*1.1,'\color{orange}q')
            elseif strcmp(Curva,'C4')
                app.UIAxes.XTick = [0,0.01,0.05,0.1,0.2,0.4,0.8,1,2];
                xlim(app.UIAxes,[0,2])%,ylim(app.UIAxes,[0.5,2])
                text(app.UIAxes,0.3,TablaAlig(find(alphaArray<=0.3,1,'first'),2)*1.1,'\color{orange}h')
                text(app.UIAxes,0.3,TablaAlig(find(alphaArray<=0.3,1,'first'),4)*0.95,'\color{orange}q')
            end
            
            % Linea recta y paralela al eje Y
            X = TablaAlig(find(QtsArray<=Qts,1,'last'),3);
            semilogx(app.UIAxes,[X,X],app.UIAxes.YLim,'-k')
            
            hold(app.UIAxes,'off')
            
            % Otros detalles de la grafica
            xlabel(app.UIAxes,'\alpha=V_{as}/V_{ab}')
            grid(app.UIAxes,'on')
            title(app.UIAxes,{['\color{red}' Nombre];['\color{black}Curves for Q_L = ' num2str(QLl) ' (' Curva ')']})
            l=legend(app.UIAxes,cu,'Q_{ts}','h=f_b/f_s','q=f_3/f_s');
            l.Location = 'north';
        end
        
        function [fb_fs,f3_fs,Qts,alfa] = reverse_alignment(~,alignment,alignment_coef,QL)
            a1 = sqrt(4 + 2*sqrt(2));
            a2 = 2 + sqrt(2);
            a3 = a1;
            Qts_B4 = 1/(a1 - 1/(QL));

            if alignment_coef == 0
                alignment = 'B4';
            end

            switch alignment
                case 'B4'
                    fb_fs = 1;
                    f3_fs = 1;
                    Qts = Qts_B4;
                    
                case 'QB3'
                    B = alignment_coef;
                    poly = [1 0 -12 -8*B^2 +4];
                    a2 = roots(poly);
                    a2 = a2(a2>(2+sqrt(2)));
                    a1 = sqrt(B^2 + 2*a2);
                    a3 = sqrt(2*a2);
                    poly2 = [1 -a3*QL 0 a1*QL -1];
                    H = roots(poly2);
                    h = H.^2;
                    Qts = H./(a1 - 1./(QL*H));
                    root_val = find(Qts>0&Qts<Qts_B4);
                    Qts = Qts(root_val);
                    h = h(root_val);
                    H = H(root_val);
                    w = 0.5:1e-3:2.5;
                    H_s = w.^8./(w.^8+B^2*w.^2+1);
                    [~,pos] = min(abs(H_s-0.5));
                    fb_fs = h;
                    f3_fs = sqrt(fb_fs)*w(pos);
            
                
                case 'C4'
                    R = alignment_coef;
                    ep = sqrt(10^(R/10)-1);
                    k = tanh(1/4*asinh(1/ep));
                    D = (k^4+6*k^2+1)/8;
                    a1 = k*sqrt(4+2*sqrt(2))/D^(1/4);
                    a2 = (1+k^2*(1+sqrt(2)))/sqrt(D);
                    a3 = a1*(1 - (1-k^2)/(2*sqrt(2)))/sqrt(D);
                    
                    poly2 = [1 -a3*QL 0 a1*QL -1];
                    H = roots(poly2);
                    h = H.^2;
                    Qts = H./(a1 - 1./(QL*H));
                    root_val = find(Qts<2&Qts>Qts_B4);
                    Qts = Qts(root_val);
                    H = H(root_val);
                    h = h(root_val);
                    fb_fs = h;
            
                    f3_fs = sqrt(fb_fs) * ((1+ep^2)/(64*ep^2))^(1/8) * 2/sqrt(2 + sqrt( 2 + 2*sqrt( 2 + 1/ep^2 )  ) );
            end
            
            alfa = (a2 - fb_fs - 1./(QL.*Qts)).*fb_fs -1;               
                        
            
        end
        
        function plotCurvesAligSpeaker(app)
            % Representa las curvas del altavoz seleccionado en el criterio por alineamiento
            
            % Buscar el altavoz en la tabla orginal
            loCell = cellfun(@(c)strcmp(c,app.TablaAlignment.Name),app.TableSpeakers.Data.Name(app.AltSelect),'UniformOutput',false);
            Index = sum(horzcat(loCell{:}),2);
            % Extraer datos
            TablaDatos = app.TablaAlignment(Index==1,:);
            TablaCurvas = app.TablaDBCurvas(Index==1,:);
            
            % Si hay resultados repetidos se deja solo 1
            if sum(Index)>1
                TablaDatos = TablaDatos(1,:);
                TablaCurvas = TablaCurvas(1,:);
            end
            
            plotSPLcurves(app,app.plotSPL,TablaDatos,TablaCurvas,1)
            plotXmaxcurves(app,app.plotXmax,TablaDatos,TablaCurvas)
            
            % Actualizar la tabla de parametros de alinemiento
            app.TableValues.Data = [TablaDatos.alpha;TablaDatos.h;TablaDatos.q];
            % Representar las curvas de alineamiento
            plotCurvesTable(app,TablaCurvas.TablaAlig{:},TablaDatos.Align_Type{:},TablaDatos.Qts,TablaDatos.QL,TablaDatos.Name{:})
            
            % Actualizar tabla de parametros
            tabla = table([round(TablaDatos.Fs,1);round(TablaDatos.f3,1);round(TablaDatos.Fb,1);...
                round(TablaDatos.Pe_max,1);round(TablaDatos.SPLmax,1);round(TablaDatos.SPL_1W,1);round(TablaDatos.Vb,1);...
                round(TablaDatos.Qts,3);round(TablaDatos.Xmax,1);round(TablaDatos.D_p,1);round(TablaDatos.L_p,1)]);
            tabla.Properties.VariableNames = {'Values'};
            units = table([{'Hz'};{'Hz'};{'Hz'};{'W'};{'dB'};{'dB'};{'liters'};...
                {' '};{'mm'};{'cm'};{'cm'}]);
            units.Properties.VariableNames = {'Units'};
            app.ParamResults.Data = [tabla,units];
            
        end
        
        function error = ImportDB(app)
            % Funcion que importa la base de datos Excel y realiza los calculos
            % para obtener todos los parámetros y curvas de cada altavoz segun
            
            % Datos de la tabla
            BaseD = app.BaseDatos; % Archivo que contiene la base de datos
            try
                NumVar = 10; % Numero de variables/columnas
                VarTipo = 'double'; % Tipo de variable de los datos general
                InitDat = 'A4'; % Posicion de inicio de los datos
                VarName = 'A2'; % Posicion de inicio del nombre de variables/columnas
                VarUnit = 'A3'; % Posicion de inicio de las unidades de las variables/columnas
                %RowName = 'A4'; % Posicion de inicio del nombre de las filas/altavoces
                
                % Vector para introducir el tipo de variable a todo
                VarTipVec = [{'string'},repmat({VarTipo},1,NumVar-1)];
                % Opciones de lectura de tabla
                opts = spreadsheetImportOptions('NumVariables',NumVar,...
                    'DataRange',InitDat,'VariableNamesRange',VarName,...
                    'VariableUnitsRange',VarUnit,'VariableTypes',VarTipVec);
                % Tabla importada
                Timp = readtable(BaseD,opts,'ReadVariableNames',1);
                error = 0;
            catch
                error = 1;
            end
            
            if ~strcmp(Timp.Properties.VariableNames{1},'Name') || error
               mensaje = 'The database was not found or is incorrect. Select a database in ''SpeakerDB->Select database...''';
                uialert(app.LoVEBASSUIFigure,mensaje,'Error',...
                    'Icon','error','Modal',true);
                error = 1;
                return 
            end
            % Agregar columnas de datos calculados inicialmente
            
            % CALCULOS GENERALES (solo son necesarias las constantes y parametros del altavoz)
            Mms = 1000./((2*pi*Timp.Fs).^2.*(Timp.Vas*10^-3./(app.rho*app.c^2*(Timp.Sd*10^-4).^2)));
            UMms = 'grams';
            
            Cms = Timp.Vas*10^-3./(app.rho*app.c^2*(Timp.Sd*10^-4).^2)*10^6;
            UCms = 'um/N';
            
            Bl = sqrt(2*pi*Timp.Fs.*Timp.Re.*(Mms*10^-3)./Timp.Qes);
            UBl = 'Tm';
            
            eta2 = (app.rho*Bl.^2.*(Timp.Sd*10^-4).^2)./(2*pi*app.c*Timp.Re.*(Mms*10^-3).^2)*100;
            Ueta = '%';
            
            Rms = (1./Timp.Qms).*sqrt((Mms*10^-3)./(Cms*10^-6));
            URms = 'kg/s';
            
            Qts2 = (Timp.Qes.*Timp.Qms)./(Timp.Qes+Timp.Qms);
            UQts = '';
            
            SPL_1W = 112.1 + 10*log10(eta2./100);
            USPL_1W = 'dB';
            
            EBP = Timp.Fs./Timp.Qes;
            UEBP = '';
            
            Enclosure = strings(size(Timp,1),1);
            Enclosure(EBP<50) = 'Sealed';
            Enclosure(EBP>=50 & EBP<=100) = 'Sealed/Ported';
            Enclosure(EBP>100 & EBP<200) = 'Ported';
            Enclosure(EBP>=200) = 'Horn';
            UEnclosure = 'Recommendation';
            
            % Crear tabla temporal con los datos nuevos hasta ahora
            Ttmp = table(Mms,Cms,Bl,eta2,Rms,Qts2,SPL_1W,EBP,Enclosure);
            Ttmp.Properties.VariableNames = [{'Mms'},{'Cms'},{'Bl'},{'eta'},{'Rms'},...
                {'Qts'},{'SPL_1W'},{'EBP'},{'Enclosure'}];
            Ttmp.Properties.VariableUnits = {...
                UMms,UCms,UBl,Ueta,URms,UQts,USPL_1W,UEBP,UEnclosure};
            
            % Guardar en la tabla de la aplicacion
            app.TablaDB = [Timp,Ttmp];
            
            % CALCULOS AVANZADOS (necesitan lo anterior y el valor de alpha y q)
            
            % Depende del criterio de cálculo se obtiene alpha y q de modos distintos
            if app.pestana == 1
                % Si se ejecuta el criterio por alineamiento se obtiene el mejor
                % alineamiento segun el Qts de cada transductor y el Vb seleccionado
                % Inicializar
                tam = height(app.TablaDB);
                TablaAlig = cell(tam,1);
                %Qts_0 = zeros(tam,1);
                QL = zeros(tam,1);
                alpha = zeros(tam,1);
                h = zeros(tam,1);
                q = zeros(tam,1);
                %peak_db = zeros(tam,1);
                for p=1:tam
                    Vb = app.VblitersEditField.Value;
                    Qts = app.TablaDB.Qts(p); % El de cada altavoz
                    % Busqueda de la tabla de alineamiento correcta segun Vb y Qts
                    [TablaAlig{p},~,Curva{p},QL(p)] = TableLoad(app,Qts,Vb,1);
                    %[alpha(p),h(p),q(p),peak_db(p)] = getValues(app,TablaAlig{p},Qts);
                    [h(p),q(p),~,alpha(p)] = reverse_alignment(app,Curva{p},0,QL(p));
                end
                % Crear tabla temporal con los nuevos datos obtenidos a traves del alineamiento para
                % incluir en la tabla de la UI al final de esta funcion
                TtmpA = table(QL,Curva',alpha,h,q);
                TtmpA.Properties.VariableNames = [{'QL'},{'Align_Type'},{'alpha'},{'h'},{'q'}];
                app.TablaDB = [app.TablaDB,TtmpA];
            elseif app.pestana == 2
                % Si se importa desde el criterio de parametros se calcula primero alfa
                alpha = app.TablaDB.Vas/app.VblitersEditField_2.Value;
                q = app.f3HzEditField.Value./app.TablaDB.Fs;
                q = q(:);
                QL = app.QlEditField.Value;
                
                % Almacena el valor de alfa y q en la tabla
                TtmpA = table(alpha,q);
                TtmpA.Properties.VariableNames = [{'alpha'},{'q'}];
                app.TablaDB = [app.TablaDB,TtmpA];
                % Para evitar errores al almacenar las curvas en la tabla, ya que
                % se almacena la variable TablaAlig sea cual sea el criterio
                TablaAlig = zeros(height(app.TablaDB),1);
            end
            
            % Calcular los parametros
            [fbTabla,f3Tabla,rizado,VbTabla,SPL_max,Pe_max,Ze,X_cono,SPL,f,d_puerto,L_puerto] = prestaciones_caja_abierta(app,alpha,q,QL,app.TablaDB,'normal');
        
            % Crear tabla temporal con los datos nuevos
            Ttmp = table(fbTabla,Pe_max,VbTabla*10^3,SPL_max,f3Tabla,rizado(:,1),d_puerto*10^2,L_puerto*10^2);
            Ttmp.Properties.VariableNames = [...
                {'Fb'},{'Pe_max'},{'Vb'},{'SPLmax'},{'f3'},{'Peak_dB'},{'D_p'},{'L_p'}];
            Ttmp.Properties.VariableUnits = {...
                'Hz','W','liters','dB','Hz','dB','cm','cm'};
            
            % Concatenar con la tabla importada
            app.TablaDB = [app.TablaDB,Ttmp];
            
            % TABLA DE CURVAS (segun criterio, ya que pueden ser modificadas posteriormente)
            % En el caso del criterio de parámetros las curvas se modifican.
            % Tabla que contiene los datos para representaciones gráficas
            if app.pestana == 1
                app.TablaDBCurvas = table(SPL.total,SPL.cono,SPL.puerto,SPL.fugas,X_cono,Ze,TablaAlig);
                app.TablaDBCurvas.Properties.VariableNames = [...
                    {'SPL_total'},{'SPL_cono'},{'SPL_puerto'},{'SPL_fugas'},{'X_cono'},{'Ze'},{'TablaAlig'}];
            elseif app.pestana == 2
                app.TablaDBCurvParam = table(SPL.total,SPL.cono,SPL.puerto,SPL.fugas,X_cono,Ze,TablaAlig);
                app.TablaDBCurvParam.Properties.VariableNames = [...
                    {'SPL_total'},{'SPL_cono'},{'SPL_puerto'},{'SPL_fugas'},{'X_cono'},{'Ze'},{'TablaAlig'}];
            end
            
            app.freq = f;
        end
        
        function findSpeakers(app)
            % Inicio de la variable que almacenara el resultado de las condiciones
            C = [];
            SPLmax = app.SPLmaxEditFieldPot.Value;
            Vb = app.VblitersEditField.Value;
            f3 = app.f3Field.Value;
            SPL1W = app.SPL1WdBEditField.Value;
            xmax = app.XmaxmmEditField.Value;
            sd = pi*(0.85*app.SizeinchesEditField.Value*2.54/2)^2;
            
            % Condicion de minimo SPLmax esperado
            if app.SPL_maxMajorCheckBox.Value; C = [C,app.TablaDB.SPLmax > SPLmax]; end
            % Condicion de maximo SPLmax esperado
            if app.SPL_maxMinorCheckBox_2.Value; C = [C,app.TablaDB.SPLmax < SPLmax]; end
            
            % Condicion de minimo Vb esperado
            if app.VbMajorCheckBox_2.Value; C = [C,app.TablaDB.Vb >= Vb]; end
            % Condicion de maximo Vb esperado
            if app.VbMinorCheckBox.Value; C = [C,app.TablaDB.Vb <= Vb]; end
            
            % Condicion de minimo f3 esperado
            if app.f3MajorCheckBox_2.Value; C = [C,app.TablaDB.f3 >= f3]; end
            % Condicion de maximo f3 esperado
            if app.f3MinorCheckBox.Value; C = [C,app.TablaDB.f3 <= f3]; end
            
            % Condicion de minimo SPL_1W esperado
            if app.SPL_1WMajorCheckBox.Value; C = [C,app.TablaDB.SPL_1W >= SPL1W]; end
            % Condicion de minimo SPL_1W esperado
            if app.SPL_1WMinorCheckBox_2.Value; C = [C,app.TablaDB.SPL_1W <= SPL1W]; end
            
            % Condicion de maximo Xmax esperado
            if app.XmaxMinorCheckBox.Value; C = [C,app.TablaDB.Xmax <= xmax]; end
            % Condicion de minimo Xmax esperado
            if app.XmaxMajorCheckBox_2.Value; C = [C,app.TablaDB.Xmax >= xmax]; end
            
            % Condicion de maximo Sd esperado
            if app.SdMinorCheckBox.Value; C = [C,app.TablaDB.Sd <= sd]; end
            % Condicion de minimo Sd esperado
            if app.SdMajorCheckBox_2.Value; C = [C,app.TablaDB.Sd >= sd]; end
            
            % Buscar los altavoces que entran dentro del rango de caracteristicas
            app.TablaAlignment = app.TablaDB(prod(C,2)==1,:);
            % En la tabla de curvas dejar los mismos resultados (para las representaciones gráficas)
            app.TablaDBCurvas = app.TablaDBCurvas(prod(C,2)==1,:);
            
            % Cargar la tabla a la UI
            if isempty(app.TablaAlignment) % Si no hay coincidencias se queda la tabla vacia
                app.TableSpeakers.Data = {};
            else
                % Añadir checkbox
                Tmp = table(false(height(app.TablaAlignment),1));
                Tmp.Properties.VariableNames = {'Plot'};
                % Tabla + checkbox en la UI
                app.TableSpeakers.Data = roundTable(app,sortrows([Tmp,app.TablaAlignment]),1);
                app.TableSpeakers.Data.Qms = round(app.TablaAlignment.Qms,2);
                app.TableSpeakers.Data.Qes = round(app.TablaAlignment.Qes,3);
                app.TableSpeakers.Data.Qts = round(app.TablaAlignment.Qts,3);
                app.TableSpeakers.Data.eta = round(app.TablaAlignment.eta,2);
                app.TableSpeakers.ColumnName = [Tmp.Properties.VariableNames,app.TablaAlignment.Properties.VariableNames];
                app.TableSpeakers.ColumnEditable(:,1) = true;
                app.TableSpeakers.ColumnWidth = [{40},{200},repmat({60},1,size(app.TableSpeakers.Data,2)-2)];
            end
            
        end
        
        function plotTable(app)
            % Obtener el indice de los altavoces elegidos
            Indexaux = app.TableSpeakers.Data.Plot;
            
            % Obtener los datos y las curvas de los altavoces elegidos
            if app.pestana == 1 && ~isempty(app.TablaDB)
                % Buscar los alavoces en la tabla original
                loCell = cellfun(@(c)strcmp(c,app.TablaAlignment.Name),app.TableSpeakers.Data.Name(Indexaux),'UniformOutput',false);
                Index = sum(horzcat(loCell{:}),2);
                % Extraer datos
                TablaDatos = app.TablaAlignment(Index>=1,:);
                TablaCurvas = app.TablaDBCurvas(Index>=1,:);
            elseif app.pestana == 2 && ~isempty(app.TablaDB)
                % Buscar los alavoces en la tabla original
                loCell = cellfun(@(c)strcmp(c,app.TablaParameters.Name),app.TableSpeakers.Data.Name(Indexaux),'UniformOutput',false);
                Index = sum(horzcat(loCell{:}),2);
                % Extraer datos
                TablaDatos = app.TablaParameters(Index>=1,:);
                TablaCurvas = app.TablaDBCurvParam(Index>=1,:);
            end
            
            % Representacion grafica
            fig = uifigure('Name','Results','Position',[50 50 700 700],'Color',[1,1,1]); % Ventana
            % Ejes 3
            ax3 = uiaxes(fig,'Position',[20 20 660 200]);
            ax3.BackgroundColor = [1,1,1];
            ax3.Title.String = 'Impedance';
            ax3.XLabel.String = 'Frequency [Hz]';
            ax3.YLabel.String = 'Module [\Omega]';
            % Ejes 2
            ax2 = uiaxes(fig,'Position',[20 240 660 200]);
            ax2.BackgroundColor = [1,1,1];
            ax2.Title.String = 'Cone displacement (Max Power) VS X_{max}';
            ax2.XLabel.String = 'Frequency [Hz]';
            ax2.YLabel.String = 'Cone displacement [mm]';
            % Ejes 1
            ax1 = uiaxes(fig,'Position',[20 480 660 200]);
            ax1.BackgroundColor = [1,1,1];
            ax1.Title.String = 'Sound Pressure Level (Max Power)';
            ax1.XLabel.String = 'Frequency [Hz]';
            ax1.YLabel.String = 'Sound Pressure [dB_{SPL}]';
            
            cc=lines(height(TablaDatos)); % Colores
            for p=1:height(TablaDatos)
                
                % SPL
                semilogx(ax1,app.freq,TablaCurvas.SPL_total(p,:),'Color',cc(p,:),'LineWidth',1.2)%#ok<*>
                hold(ax1,'on')
                xlim(ax1,[10 1000])
                xticks(ax1,[20 50 100 200 500 1000])
                ymax = ceil((max(max(TablaCurvas.SPL_total))+2)/10)*10;
                ylim(ax1,[-40 0]+ymax);
                grid(ax1,'on')
                % X displacement
                a(p) = semilogx(ax2,app.freq,TablaCurvas.X_cono(p,:)*1000,'Color',cc(p,:),'LineWidth',1.2);
                hold(ax2,'on')
                semilogx(ax2,[app.freq(1) app.freq(end)],TablaDatos.Xmax(p,:)*[1 1],'Color',cc(p,:),'LineStyle',':','LineWidth',1.2);
                grid(ax2,'on')
                xlim(ax2,[10 1000])
                ylim(ax2,[0,max(15,max(max(TablaDatos.Xmax))+2)])
                xticks(ax2,[20 50 100 200 500 1000])
                % Z
                semilogx(ax3,app.freq,abs(TablaCurvas.Ze(p,:)),'Color',cc(p,:),'LineWidth',1.2)
                hold(ax3,'on')
                xlim(ax3,[10 1000])
                xticks(ax3,[20 50 100 200 500 1000])
                ymax = ceil((max(max(abs(TablaCurvas.Ze(:,1:find(app.freq>=1000,1)))))+2)/10)*10;
                ylim(ax3,[0,ymax+10]);
                grid(ax3,'on')
                
            end
            legend(ax1,TablaDatos.Name,'Location','eastoutside');
            legend(a,TablaDatos.Name,'Location','eastoutside');
            legend(ax3,TablaDatos.Name,'Location','eastoutside');
            
        end
        
        function calcCustom(app)
            Vb = app.VbCustom.Value*10^-3; % meters^3
            f3 = app.f3Custom.Value;
            SPLmax = app.SPLmaxCustom.Value;
            Sd = pi*(0.85*app.SizeCustom.Value*2.54*1e-2/2)^2; % meters^2
            Re = app.ReCustom.Value;
            QL = app.QL_Custom.Value;
            AlignCoef = app.AlignCoefCustom.Value;
            type = app.AlignmenttypeDropDown.Value;
            
            % Carga la tabla correspondiente
  %          [TablaAlig,~,Curva,QL] = TableLoad(app,Qts,Vb*10^3,2,type);
  %          [alpha,h,q,peak_db] = getValues(app,TablaAlig,Qts);
            [h,q,Qts,alpha] = reverse_alignment(app,type,AlignCoef,QL);
            if strcmp(type,'C4')
                peak_db = AlignCoef;
            else
                peak_db = 0;
            end
            Qms = 4;
            
            % h = fb_fs, q = f3_fs
            % Calculos de parámetros lineales
            fs = f3/q;
            Vas = alpha*Vb;
            Qes = Qts*Qms./(Qms - Qts);
            Eff = 4*pi^2/app.c^3 * fs.^3.*Vas./Qes;
            SPL_1W = 112.1 + 10*log10(Eff);
            % Otros parametros derivados
            Cms = Vas/(app.rho*app.c^2*Sd^2);
            Mms = 1/((2*pi*fs)^2*Cms);
            Bl = sqrt(Re/Qes*sqrt(Mms/Cms));
            Rms = 1/Qms*sqrt(Mms/Cms);
            
            % Almacenar en tabla
            Xmax = 1e-3; % Valor inicial
            Pe = 1e6; % Valor inicial
            Tabla = table(fs,Re,Bl,Mms*10^3,Cms*10^6,Rms,Pe,Xmax*10^3,Vas*10^3,Eff*100,Sd*10^4);
            Tabla.Properties.VariableNames = [{'Fs'},{'Re'},{'Bl'},{'Mms'},{'Cms'},...
                {'Rms'},{'Pe'},{'Xmax'},{'Vas'},{'eta'},{'Sd'}];
            % Parámetros no lineales
            
            [fb,f3,rizado,Vb,SPL_max_1mm,Pe_max_1mm,Ze,X_cono,SPL,app.freq,d_puerto,L_puerto] = prestaciones_caja_abierta(app,alpha,h,QL,Tabla,'normal');
            Incremento_potencia = 10^((SPLmax-SPL_1W)/10);
            Pe = Incremento_potencia;
            
            Incremento_X = sqrt(10^((SPLmax - SPL_max_1mm)/10));
            Xmax = Incremento_X*1e-3;
             
            % Recalcular actualizando el Xmax y la Pe y fijando el SPL maximo
            Tabla.Xmax = Xmax*1e3;
            Tabla.Pe = Pe;
            [fb,f3,rizado,Vb,SPL_max_1mm,Pe_max_1mm,Ze,X_cono,SPL,app.freq,d_puerto,L_puerto] = prestaciones_caja_abierta(app,alpha,h,QL,Tabla,'SPLfixed',SPLmax);
            
            % Representacion gráfica
            % Se deben crear primero las tablas finales
            tNew = table(fb,f3,Vb*1000,SPL_max_1mm,Pe_max_1mm,d_puerto*100,L_puerto*100,Xmax*1000,{'Custom Speaker'},Pe);
            tNew.Properties.VariableNames = [{'Fb'},{'f3'},{'Vb'},{'SPLmax'},{'Pe_max'},{'D_p'},{'L_p'},{'Xmax'},{'Name'},{'Pe'}];
            tNewCurv = table(SPL.total,SPL.cono,SPL.puerto,X_cono);
            tNewCurv.Properties.VariableNames = [{'SPL_total'},{'SPL_cono'},{'SPL_puerto'},{'X_cono'}];
            
            plotSPLcurves(app,app.plotSPLCustom,tNew,tNewCurv,2)
            plotXmaxcurves(app,app.plotXmaxCustom,tNew,tNewCurv)
            
            % Mostrar datos en la tabla
            tabla = table([round(Qts,3);round(Qms,3);round(Qes,3);round(fs,1);round(Pe,1);round(Pe/2,1);round(Eff*100,2);round(SPL_1W,1);...
                round(SPL_max_1mm,1);round(Vas*1000,1);round(Sd*10^4,1);round(Cms*10^6,1);round(Mms*1000,1);round(Bl,1);round(Re,1);round(Rms,1);round(Xmax*1000,1)]);
            tabla.Properties.VariableNames = {'Values'};
            units = table([{' '};{' '};{' '};{'Hz'};{'W'};{'W'};{'%'};{'dB'};...
                {'dB'};{'l'};{['cm' char(178)]};{[char(956) 'm/N']};{'g'};{'N/A'};{char(937)};{'kg/s'};{'mm'}]);
            units.Properties.VariableNames = {'Units'};
            app.ParamResultsCustom.Data = [tabla,units];
            
            
            % Revisar advertencias
            Pe_check = (app.SizeCustom.Value)^2*10;
            Xmax_check = (app.SizeCustom.Value/1.2)*1e-3;
            Mms_check = Sd*0.9;
            Bl_check = 2*app.SizeCustom.Value;
            Warnings = zeros(4,1);
            
            i = 1;
            str(i) = {sprintf('The following warnings were detected, check for feasibility: \n')};
            if Mms<Mms_check
                i = i+1;               
                str(i) = {sprintf('- Low moving mass. This could provoke excessive resonant modes in the diafragm, unless light but rigid materials are used. \n')};
                Warnings(1) = 1;
                
            end
           
            if Xmax > Xmax_check
                i = i+1;               
                str(i) = {sprintf('- High linear excursion, Xmax. This could cause audible distorsion. \n') };
                Warnings(2) = 1;
            end
            
            if Pe > Pe_check
                i = i+1;               
                str(i) = {sprintf('- High electrical input power.\n')};
                Warnings(3) = 1;
            end

            if Bl > Bl_check
                i = i+1;               
                str(i) = {sprintf('- High Bl value.\n')};
                Warnings(4) = 1;
            end
            if i ==1
                str(i) = {'No warnings detected.'};
            else
                WarningsCode = [8 4 2 1]*Warnings;
                i = i+1;
                str(i) = {sprintf('\n')};
                i = i+1;
                str(i) = {sprintf('In order to troubleshoot these problems, the following actions could be taken: \n')}
                switch WarningsCode
                    case 1
                        i = i+1;
                        str(i) = {sprintf('Solution 1: decrease Re \n')}
                        i = i+1;
                        str(i) = {sprintf('Solution 2: decrease speaker size \n')}
                        i = i+1;
                        str(i) = {sprintf('Solution 3: increase box volume, Vb \n')}
                        i = i+1;
                        str(i) = {sprintf('Solution 4: increase lower cuttoff frequency, f3 \n')}
                    case 2
                        i = i+1;
                        str(i) = {sprintf('Solution 1: decrease the maximum achievable SPL, SPLmax \n')}
                        i = i+1;
                        str(i) = {sprintf('Solution 2: increase box volume, Vb \n')}
                        i = i+1;
                        str(i) = {sprintf('Solution 3: increase lower cuttoff frequency, f3 \n')}
                    case 3|5
                        i = i+1;
                        str(i) = {sprintf('Solution 1: increase lower cuttoff frequency, f3\n') }
                        i = i+1;
                        str(i) = {sprintf('Solution 2: increase box volume, Vb \n')}
                        i = i+1;
                        str(i) = {sprintf('Solution 3: decrease both SPLmax and Re \n')}
                        i = i+1;
                        str(i) = {sprintf('Solution 4: decrease both SPLmax and speaker size\n' )}
                    case 4
                        i = i+1;
                        str(i) = {sprintf('Solution 1: increase speaker size \n' )}
                        i = i+1;
                        str(i) = {sprintf('Solution 2: decrease the maximum achievable SPL, SPLmax \n')}
                        i = i+1;
                        str(i) = {sprintf('Solution 3: increase lower cuttoff frequency, f3 \n' )}
                    case 6
                        i = i+1;
                        str(i) = {sprintf('Solution 1: decrease the maximum achievable SPL, SPLmax \n')}
                        i = i+1;
                        str(i) = {sprintf('Solution 2: increase lower cuttoff frequency, f3\n' )}
                        i = i+1;
                        str(i) = {sprintf('Solution 3: increase both the speaker size and box volume Vb \n')}
                    case 7
                        i = i+1;
                        str(i) = {sprintf('Solution 1: increase lower cuttoff frequency, f3 \n')}
                        i = i+1;
                        str(i) = {sprintf('Solution 2: decrease both SPLmax and Re \n')}
                        i = i+1;
                        str(i) = {sprintf('Solution 3: decrease both SPLmax and speaker size \n')}
                    case 8
                        i = i+1;
                        str(i) = {sprintf('Solution 1: increase speaker size \n')}
                        i = i+1;
                        str(i) = {sprintf('Solution 2: decrease box volume, Vb \n')}
                        i = i+1;
                        str(i) = {sprintf('Solution 3: decrease lower cuttoff frequency, f3 \n')}

                    case 9
                        i = i+1;
                        str(i) = {sprintf('Start decreasing Re until Bl warnings is fixed. Then continue with: \n')}
                        i = i+1;
                        str(i) = {sprintf('Solution 1: dincrease speaker size \n')}
                        i = i+1;
                        str(i) = {sprintf('Solution 2: decrease box volume, Vb \n')}
                        i = i+1;
                        str(i) = {sprintf('Solution 3: decrease lower cuttoff frequency, f3 \n')}

                    case 10|14
                        i = i+1;
                        str(i) = {sprintf('Increase speaker size and decrease SPLmax \n')}
                    case 11|15
                        i = i+1;
                        str(i) = {sprintf('Decrease Re, increase speaker size and decrease SPLmax \n')}
                    case 12
                        i = i+1;
                        str(i) = {sprintf('Solution 1: increase speaker size \n')}
                        i = i+1;
                        str(i) = {sprintf('Solution 2: decrease both Vb and SPLmax \n')}
                    case 13
                        i = i+1;
                        str(i) = {sprintf('Start decreasing Re until Bl warnings is fixed. Then continue with: \n')}
                        i = i+1;
                        str(i) = {sprintf('Solution 1: dincrease speaker size \n')}
                        i = i+1;
                        str(i) = {sprintf('Solution 2: decrease both Vb and SPLmax \n')}
                end
            end
                        
            app.WarningsTexto.Value = str;
        end
        
        function error = calcParametersCriter(app)
            app.ProcessedspeakersLabel.Text = 'Processed speakers';
            % Funcion que realiza los calculos mediante el criterio de parametros
            % Hace uso de las siguientes funciones:
            % calcula_fb_validos
            % prestaciones_caja_abierta
            % calcula_Vb_Validos
            % escoge_mejor_altavoz
            
            % Parametros del usuario
            Vbpar = app.VblitersEditField_2.Value*10^-3;
            rizado = [app.LFboostdBEditField.Value,app.RippledBEditField.Value];
            Sd_objetivo = (app.SizeEditField.Value*2.54/(2*1.15))^2*pi/1e4;
            f3par = app.f3HzEditField.Value;
            SPLmaxpar = app.SPLmaxdBEditField.Value;
            EBPmin = app.EBPEditField.Value;
            Ql = app.QlEditField.Value;
            
            
            N_altavoces = height(app.TablaDB);
            
            % Parametro alpha de cada altavoz
            alfa = app.TablaDB.alpha;
            
            Index = [];
            fbpar = [];
            D_puerto = [];
            L_puerto = [];
            maxSPL = [];
            f3min = [];
            Vbmin = [];
            rizado_a = [];
            
            
            for i=1:N_altavoces
                drawnow; app.BarraCarga.Value = i;
                fb_actual = [];
                if (app.TablaDB.Sd(i)*10^-4 < Sd_objetivo) &&...
                        (app.TablaDB.EBP(i)>=EBPmin) &&...
                        (app.TablaDB.Fs(i)<2*f3par)
                    [fb_actual,D_puerto_actual,L_puerto_actual,maxSPL_actual,f3min_actual,rizado_actual] = ...
                        calcula_fb_validos(app,alfa(i),rizado,f3par,SPLmaxpar,Ql,i);
                end
                altavoz_valido = not((isempty(fb_actual)));
                if altavoz_valido == 1
                    Index = [Index i];%#ok<AGROW>
                    fbpar = [fbpar; fb_actual];%#ok<AGROW>
                    D_puerto = [D_puerto; D_puerto_actual];%#ok<AGROW>
                    L_puerto = [L_puerto; L_puerto_actual];%#ok<AGROW>
                    maxSPL = [maxSPL; maxSPL_actual];%#ok<AGROW>
                    f3min = [f3min; f3min_actual];%#ok<AGROW>
                    rizado_a = [rizado_a;rizado_actual];%#ok<AGROW>
                    
                    [Vb_actual(1)] = calcula_Vb_validos(app,Vbpar,fb_actual(2),rizado,f3par,SPLmaxpar,Ql,i);
                    Vbmin = [Vbmin; Vb_actual];%#ok<AGROW>
                    %disp(app.TablaDB.Name(i))
                    
                end
            end
            
            app.ProcessedspeakersLabel.Text = 'Loading...';drawnow;
            
            if isempty(rizado_a)
                error = true;
                message = sprintf('No speakers found! \n Check the parameters entered.');
                uialert(app.LoVEBASSUIFigure,message,'Warning','Icon','warning');
            else
                error = false;
                % Extrae de la base de datos los altavoces compatibles
                app.TablaParameters = app.TablaDB(Index,:);
                app.TablaDBCurvParam = app.TablaDBCurvParam(Index,:);
                
                % Actualiza los parametros de los altavoces con los calculados
                app.TablaParameters.Peak_dB = rizado_a(:,1);
                app.TablaParameters.D_p = D_puerto*10^2;
                app.TablaParameters.L_p = L_puerto*10^2;
                app.TablaParameters.f3_min = f3min;
                app.TablaParameters.Fb = fbpar(:,2);
                app.TablaParameters.Vb_min = Vbmin;
                app.TablaParameters.SPLmax = maxSPL;
                % Se recalcula alpha y q
                app.TablaParameters.alpha = app.TablaParameters.Vas./Vbmin;
                app.TablaParameters.q = fbpar(:,2)./app.TablaParameters.Fs;
                
                criterio{1} = 'maxSPL';
                criterio{2} = 'minf3';
                criterio{3} = 'minVb';
                criterio{4} = 'ShortestPort';
                
                % Inicializaciones
                altavoz = zeros(4,1);
                Vb_final = zeros(4,1);
                fb_final = zeros(4,1);
                f3_final = zeros(4,1);
                rizado_final = zeros(4,2);
                SPL_max_final = zeros(4,1);
                Pe_max_final = zeros(4,1);
                Ze_final = zeros(4,10000);
                X_cono_final = zeros(4,10000);
                SPL_final = cell(1,4);
                Dp_final = zeros(4,1);
                Lp_final = zeros(4,1);
                
                
                for i = 1:4
                    [altavoz(i), Vb_final(i), fb_final(i)] = ...
                        escoge_mejor_altavoz(app,criterio{i},Vbmin,Vbpar*1000,fbpar,maxSPL,f3min,L_puerto);
                    alfa = app.TablaDB.Vas(Index(altavoz(i)))*10^-3 / (Vb_final(i));
                    fb_fs = fb_final(i)/app.TablaDB.Fs(Index(altavoz(i)));
                    if i<4
                        [~,f3_final(i),rizado_final(i,:),Vb_final(i),SPL_max_final(i),...
                            Pe_max_final(i),Ze_final(i,:),X_cono_final(i,:),SPL_final{i},f,Dp_final(i),Lp_final(i)] =...
                            prestaciones_caja_abierta(app,alfa,fb_fs,Ql,app.TablaDB(Index(altavoz(i)),:),'normal');
                    else
                        [~,f3_final(i),rizado_final(i,:),Vb_final(i),SPL_max_final(i),...
                            Pe_max_final(i),Ze_final(i,:),X_cono_final(i,:),SPL_final{i},f,Dp_final(i),Lp_final(i)] =...
                            prestaciones_caja_abierta(app,alfa,fb_fs,Ql,app.TablaDB(Index(altavoz(i)),:),'SPLfixed',SPLmaxpar);
                    end
                end
                % Actualizar valores en la tabla para los altavoces recomendados
                TablaRecomendados = app.TablaParameters(altavoz,:);
                
                TablaRecomendados.f3 = f3_final;
                TablaRecomendados.Fb = fb_final;
                TablaRecomendados.Peak_dB = rizado_final(:,1);
                TablaRecomendados.Vb = Vb_final*10^3;
                TablaRecomendados.SPLmax = SPL_max_final;
                TablaRecomendados.D_p = Dp_final*10^2;
                TablaRecomendados.L_p = Lp_final*10^2;
                TablaRecomendados.Pe_max = Pe_max_final;
                
                % Modificar las curvas para los altavoces recomendados
                 CurvasRecomendados = app.TablaDBCurvParam(altavoz,:);
                 CurvasRecomendados.SPL_total = [SPL_final{1}.total;SPL_final{2}.total;SPL_final{3}.total;SPL_final{4}.total];
                 CurvasRecomendados.SPL_cono = [SPL_final{1}.cono;SPL_final{2}.cono;SPL_final{3}.cono;SPL_final{4}.cono];
                 CurvasRecomendados.SPL_puerto = [SPL_final{1}.puerto;SPL_final{2}.puerto;SPL_final{3}.puerto;SPL_final{4}.puerto];
                 CurvasRecomendados.SPL_fugas = [SPL_final{1}.fugas;SPL_final{2}.fugas;SPL_final{3}.fugas;SPL_final{4}.fugas];
                 CurvasRecomendados.Ze = Ze_final;
                 CurvasRecomendados.X_cono = X_cono_final;
                
                app.freq = f;
                % Almacenar la posicion de los altavoces recomendado en la tabla TableParameters
                app.IndexRecom = altavoz;
                
                % Actualizar la tabla UI
                % Cargar la tabla a la UI
                if isempty(app.TablaParameters) % Si no hay coincidencias se queda la tabla vacia
                    app.TableSpeakers.Data = {};
                else
                    % Añadir checkbox
                    Tmp = table(false(height(app.TablaParameters),1));
                    Tmp.Properties.VariableNames = {'Plot'};
                    % Tabla + checkbox en la UI
                    app.TableSpeakers.Data = roundTable(app,sortrows([Tmp,app.TablaParameters]),1);
                    app.TableSpeakers.Data.Qms = round(app.TablaParameters.Qms,2);
                    app.TableSpeakers.Data.Qes = round(app.TablaParameters.Qes,2);
                    app.TableSpeakers.Data.Qts = round(app.TablaParameters.Qts,2);
                    app.TableSpeakers.Data.eta = round(app.TablaParameters.eta,2);
                    app.TableSpeakers.ColumnName = [Tmp.Properties.VariableNames,app.TablaParameters.Properties.VariableNames];
                    app.TableSpeakers.ColumnEditable(:,1) = true;
                    app.TableSpeakers.ColumnWidth = [{40},{200},repmat({60},1,size(app.TableSpeakers.Data,2)-2)];
                    
                    % Preseleccionar los recomendados para plotear
                    
                    % Buscar donde estan en la tabla
                    loCell = cellfun(@(c)strcmp(c,app.TablaDB.Name(Index(altavoz))),app.TableSpeakers.Data.Name,'UniformOutput',false);
                    Index2 = sum(horzcat(loCell{:}),1);
                    app.TableSpeakers.Data.Plot = logical(Index2');
                end
                
                % Representaciones graficas
                
                % Maximo SPL
                plotSPLcurves(app,app.MaxSPLplotSPL,TablaRecomendados(1,:),CurvasRecomendados(1,:),1)
                app.MaxSPLplotSPL.Title.String = [{['Maximum SPL with:\color{red} ' TablaRecomendados.Name{1}]}; app.MaxSPLplotSPL.Title.String(2:3)];
                plotXmaxcurves(app,app.MaxSPLplotXmax,TablaRecomendados(1,:),CurvasRecomendados(1,:))
                % Datos para maximo SPL
                app.f_bMaxSPL.Value = TablaRecomendados(1,:).Fb;
                app.VbMaxSPL.Value = TablaRecomendados(1,:).Vb;
                app.SPLMaxSPL.Value = TablaRecomendados(1,:).SPLmax;
                
                % Minimo f3
                plotSPLcurves(app,app.Minf3plotSPL,TablaRecomendados(2,:),CurvasRecomendados(2,:),1)
                app.Minf3plotSPL.Title.String = [{['Minimal f_3 with:\color{red} ' TablaRecomendados.Name{2}]}; app.Minf3plotSPL.Title.String(2:3)];
                plotXmaxcurves(app,app.Minf3plotXmax,TablaRecomendados(2,:),CurvasRecomendados(2,:))
                % Datos para minimo f3
                app.f_bMinf3.Value = TablaRecomendados(2,:).Fb;
                app.VbMinf3.Value  = TablaRecomendados(2,:).Vb;
                app.SPLMinf3.Value = TablaRecomendados(2,:).SPLmax;
                
                % Minimo Vb
                plotSPLcurves(app,app.MinVbplotSPL,TablaRecomendados(3,:),CurvasRecomendados(3,:),1)
                app.MinVbplotSPL.Title.String = [{['Minimal V_b with:\color{red} ' TablaRecomendados.Name{3}]}; app.MinVbplotSPL.Title.String(2:3)];
                plotXmaxcurves(app,app.MinVbplotXmax,TablaRecomendados(3,:),CurvasRecomendados(3,:))
                % Datos para minimo Vb
                app.f_bMinVb.Value = TablaRecomendados(3,:).Fb;
                app.VbMinVb.Value  = TablaRecomendados(3,:).Vb;
                app.SPLMinVb.Value = TablaRecomendados(3,:).SPLmax;
                
                % Minimo puerto
                plotSPLcurves(app,app.MinPortplotSPL,TablaRecomendados(4,:),CurvasRecomendados(4,:),1)
                app.MinPortplotSPL.Title.String = [{['Shortest port with:\color{red} ' TablaRecomendados.Name{3}]}; app.MinPortplotSPL.Title.String(2:3)];
                plotXmaxcurves(app,app.MinPortplotXmax,TablaRecomendados(4,:),CurvasRecomendados(4,:))
                % Datos para minimo puerto
                app.f_bMinPort.Value = TablaRecomendados(4,:).Fb;
                app.VbMinPort.Value  = TablaRecomendados(4,:).Vb;
                app.SPLMinPort.Value = TablaRecomendados(4,:).SPLmax;
                
            end
        end
        
        function plotSPLcurves(app,axes,tablaData,tablaCur,type)
            
            f = app.freq;
            SPLtotal = tablaCur.SPL_total;
            SPLcono = tablaCur.SPL_cono;
            SPLpuerto = tablaCur.SPL_puerto;
            f_3 = tablaData.f3;
            SPL_max = tablaData.SPLmax;
            V_b = tablaData.Vb;
            fb = tablaData.Fb;
            Dp = tablaData.D_p;
            Lp = tablaData.L_p;
            Pemax = tablaData.Pe_max;
            Penom = tablaData.Pe;
            Nombre = tablaData.Name{:};
           
            
            semilogx(axes,f,SPLcono,'b');
            hold(axes,'on')
            semilogx(axes,f,SPLpuerto,'r');
            semilogx(axes,f,SPLtotal,'k','LineWidth',2);
            semilogx(axes,[f_3 f_3],[0 200],'k--');
            hold(axes,'off')
            xlim(axes,[20 1000])
            xticks(axes,[20 50 100 200 500 1000])
            ymax = ceil((max(SPL_max,max(SPLtotal))+2)/10)*10;
            ylim(axes,[-40 0]+ymax);
            grid(axes,'on')
            legend(axes,'Cone','Port','Total','Location','SouthEast')
            ylabel(axes,'Sound Pressure [dB_{SPL}]')
            switch type
                case 1
                    title(axes,{['\color{red}' Nombre];...
                        ['\color{black}  V_b = ' num2str(round(V_b)) ' liters, f_b = ' num2str(round(fb)) ' Hz,  f_3 = ' num2str(round(f_3)) 'Hz, SPL = ' num2str(round(2*SPL_max)/2) ' dB_{SPL}'];...
                        ['D_p = ' num2str(round(Dp*1e2)/100) ' cm,  L_p = ' num2str(round(Lp*1e2)/100) ' cm,  P_{e_{max}} = ' num2str(ceil(Pemax)) ' W, P_{e_{nom}} = ' num2str(ceil(Penom)) ' W']})
                case 2
                    title(axes,{['\color{red}' Nombre];...
                        ['\color{black}  V_b = ' num2str(round(V_b)) ' liters, f_b = ' num2str(round(fb)) ' Hz,  f_3 = ' num2str(round(f_3)) 'Hz, SPL = ' num2str(round(2*SPL_max)/2) ' dB_{SPL}'];...
                        ['D_p = ' num2str(round(Dp*1e2)/100) ' cm,  L_p = ' num2str(round(Lp*1e2)/100) ' cm,  P_e = ' num2str(ceil(Penom)) ' W']})
            end
            xlabel(axes,'Frequency [Hz]')
            
        end
        
        function plotXmaxcurves(app,axes,tablaData,tablaCur)
            
            f = app.freq;
            Xcono = tablaCur.X_cono;
            Xmax = tablaData.Xmax;
            fb = tablaData.Fb;
            XmaxRel = max(Xcono(find(f<=fb,1,'last'):end))*1e3;
            
            semilogx(axes,f,Xcono*1000,'b')
            hold(axes,'on')
            semilogx(axes,[f(1) f(end)],Xmax*[1 1],'r')
            hold(axes,'off')
            grid(axes,'on')
            xlim(axes,[20 1000])
            ylim(axes,[0,max(XmaxRel,Xmax)*2])
            xticks(axes,[20 50 100 200 500 1000])
            legend(axes,'Cone','X_{max}')
            xlabel(axes,'Frequency [Hz]')
            ylabel(axes,'Cone displacement [mm]')
        end
        
        function adjustResults(app,axes1,axes2,type,Vb,fb,SPLmax)
            % Type indica que tipo de recomendacion es
            % 1 = Maximo SPL, 2 = Minimo f3, 3 = Minimo Vb, 4 = Min puerto
            
            % Extraer altavoz recomendado para maximo SPL
            tabla = app.TablaParameters(app.IndexRecom(type),:);
            % Recalcular alpha y q
            alpha = tabla.Vas/Vb;
            h = fb/tabla.Fs;
            
            % Actualizar la tabla con los valores de las casillas
            tabla.Vb = Vb/1000;
            tabla.SPLmax = SPLmax;
            tabla.Fb = fb;
            
            % Recalcular
            [fb,f_3,~,V_b,SPL_max,Pe_max,~,X_cono,SPL,~,d_puerto,L_puerto] =...
                prestaciones_caja_abierta(app,alpha,h,app.QlEditField.Value,tabla,'SPLfbVbfixed',SPLmax,fb,Vb/1000);
            
            % Crear tabla con los nuevos datos
            tNew = table(fb,f_3,V_b*1000,SPL_max,Pe_max,d_puerto*100,L_puerto*100,tabla.Xmax,tabla.Name,tabla.Pe);
            tNew.Properties.VariableNames = [{'Fb'},{'f3'},{'Vb'},{'SPLmax'},{'Pe_max'},{'D_p'},{'L_p'},{'Xmax'},{'Name'},{'Pe'}];
            tNewCurv = table(SPL.total,SPL.cono,SPL.puerto,X_cono);
            tNewCurv.Properties.VariableNames = [{'SPL_total'},{'SPL_cono'},{'SPL_puerto'},{'X_cono'}];
            
            % Representar los cambios
            plotSPLcurves(app,axes1,tNew,tNewCurv,1)
            plotXmaxcurves(app,axes2,tNew,tNewCurv)
            
        end
        function [fb_range,D_puerto,L_puerto,maxSPL,f3min,rizado_est] = calcula_fb_validos(app,alfa,rizado,f3,SPLobjetivo,QL,k)
            
            fb_range = [];
            maxSPL = [];
            f3min = [];
            
            fbpar = round(0.5*f3):round(2*f3);
            N_iteraciones = length(fbpar);
            % Inicializaciones
            f3_est = zeros(1,N_iteraciones);
            rizado_est = zeros(N_iteraciones,2);
            SPL_max_est = zeros(1,N_iteraciones);
            D_puerto = zeros(1,N_iteraciones);
            L_puerto = zeros(1,N_iteraciones);
            for i=1:N_iteraciones
                fb_fs = fbpar(i)/app.TablaDB.Fs(k);
                [~,f3_est(i),rizado_est(i,:),~,SPL_max_est(i),~,~,~,~,~,D_puerto(i),L_puerto(i)] = prestaciones_caja_abierta(app,alfa,fb_fs,QL,app.TablaDB(k,:),'normal');
            end
            
            Index_f3 = find(f3_est<=f3);
            Index_rizado_est_1 = find(rizado_est(:,1)-rizado(1)<=0);
            if length(rizado)==2
                Index_rizado_est_2 = find(rizado_est(:,2)-rizado(2)<=0);
            else
                Index_rizado_est_2 = 1:length(fbpar);
            end
            Index_SPLmax = find(SPL_max_est>=SPLobjetivo);
            Index = intersect(Index_f3,Index_rizado_est_1);
            Index = intersect(Index,Index_rizado_est_2);
            Index = intersect(Index,Index_SPLmax);
            if ~isempty(Index)
                [maxSPL,posSPLmax] = max(SPL_max_est(Index(1):Index(end)));
                fbSPLmax = fbpar(posSPLmax+Index(1)-1);
                [f3min,posf3min] = min(f3_est(Index(1):Index(end)));
                fbf3min = fbpar(posf3min+Index(1)-1);
                
                
                [~,posLpuertomin] = min(L_puerto(Index));
                %     D_puerto = D_puerto(posLpuertomin+Index(1)-1);
                fb_Lpuertomin = fbpar(posLpuertomin+Index(1)-1);
                
                fb_fs = fb_Lpuertomin/app.TablaDB.Fs(k);
                [~,~,rizado_est,~,~,~,~,~,~,~,D_puerto,L_puerto] = prestaciones_caja_abierta(app,alfa,fb_fs,QL,app.TablaDB(k,:),'SPLfixed',SPLobjetivo);
                
                fb_range = [fbpar(Index(1)) fbpar(Index(end)) fbSPLmax fbf3min fb_Lpuertomin];
                
            end
        end
        
        function [fb,f3,rizado,Vb,SPL_max,Pe_max,Ze,X_cono,SPL,f,d_puerto,L_puerto] = prestaciones_caja_abierta(app,alpha,h,QL,Tabla,tipo,varargin)
            
            switch tipo
                case 'normal'
                case 'SPLfixed' % Utilizado para calculos donde se busca obtener un tamaño de puerto minimo
                    SPLmaxx = varargin{1};
                case 'fbfixed'
                    fb = varargin{1};
                case 'Vbfixed'
                    Vb = varargin{1};
                case 'SPLfbVbfixed' % Utilizado en el ajuste de parametros por el usuario
                    SPLmaxx = varargin{1};
                    fb = varargin{2};
                    Vb = varargin{3};
                otherwise
                    error('No type')
            end
            
            fs = Tabla.Fs;
            Re = Tabla.Re;
            Bl = Tabla.Bl;
            Mms = Tabla.Mms*10^-3;
            Cms = Tabla.Cms*10^-6;
            Rms = Tabla.Rms;
            Pe = Tabla.Pe;
            Xmax = Tabla.Xmax*10^-3;
            Vas = Tabla.Vas*10^-3;
            Eff = Tabla.eta/100;
            Sd = Tabla.Sd*10^-4;
            
            %vectores de frecuencia y pulsación angular con espaciado logarítmico
            f = logspace(0,4,10000)*2; %rango desde 10^0Hz hasta 10^4Hz
            w = 2*pi*f;
            
            % Compliancia equivalente del volumen (m/N)
            Cmb = Cms./alpha;
            
            % Frecuencia de resonancia de la caja (Hz)
            if ~strcmp(tipo,'fbfixed') && ~strcmp(tipo,'SPLfbVbfixed')
                fb = h.*fs;
            end
            
            % Masa equivalente del puerto (kg)
            Mmp = 1./((2*pi*fb).^2.*Cmb);
            
            % Resistencia mecánica equivalente debida a las fugas
            RL = QL.*sqrt(Mmp./Cmb);
            
            % Impedancia correspondiente al circuito resonante de Helmholtz
            Zbp = 1./(1./RL + 1./(1j*w.*Mmp) + 1j*w.*Cmb);
            
            % Impedancia mecánica del conjunto altavoz + volumen-puerto
            Z = Bl.^2./Re+Rms + 1j*w.*Mms + 1./(1j*w.*Cms) + Zbp;
            
            % Tension RMS a 1W (V)
            vg_1W = sqrt(1*Re);
            
            % Fuerza mecanica a 1W
            f0_1W = Bl./Re.*vg_1W;
            
            % Velocidad a 1W (m/s)
            u_1W = f0_1W./Z;
            
            % Desplazamiento a 1W (m)
            X_1W = u_1W./(1j*w);
            
            % Curva de aceleración del cono a 1W (m/s^2)
            fuerza_react = f0_1W.*Zbp./Z;
            u_total_1W = fuerza_react.*1j.*w.*Cmb;
            a_total_1W = abs(1j*w.*u_total_1W);
            
            % Desplazamiento de pico a 1W (m)
            X_pico_1W = zeros(size(a_total_1W,1),1);
            for i = 1:size(a_total_1W,1)
                %frecuencia control para X. La definimos como la frecuencia a la que la
                %aceleracion está a -6dB (es decir, la mitad)
                pos_f_control_X = find(a_total_1W(i,:)<a_total_1W(i,end)/2);
                % Pico para f>fb y con corrección de RMS a valor de pico
                X_pico_1W(i) = max(abs(X_1W(i,pos_f_control_X(end):end)))*sqrt(2);
            end
            
            % Potencia electrica maxima (W) segun Xmax
            Pe_Xmax = (Xmax./X_pico_1W).^2;
            
            switch tipo
                case {'normal','fbfixed','Vbfixed'}
                    % Potencia electrica maxima (W).
                    % El minimo entre el RMS del fabricante y el calculado
                    Pe_max = min(Pe,Pe_Xmax);
                case {'SPLfixed','SPLfbVbfixed'}
                    % Potencia eléctrica para alcanzar SPLmax
                    Pe_SPLmax = (10^((SPLmaxx - 112.1)/10))/Eff;
                    
                    % Potencia electrica maxima (W).
                    % El minimo entre el RMS del fabricante, el calculado y segun SPLmax elegido
                    Pe_max = min([Pe Pe_Xmax Pe_SPLmax]);
            end
            
            % SPL maximo (dB)
            SPL_max = 112.1 + 10*log10(Eff) + 10*log10(Pe_max);
            
            % Curva de desplazamiento del cono (m)
            X_cono = abs(X_1W).*sqrt(2*Pe_max);
            
            % Volumen de la caja (m^3)
            if ~strcmp(tipo,'Vbfixed') && ~strcmp(tipo,'SPLfbVbfixed')
                Vb = Vas./alpha;
            end
            
            % Fuerza mecanica maxima
            f0_max = f0_1W.*sqrt(Pe_max);
            % Fuerza mecanica reactiva
            fuerza_react = f0_max.*Zbp./Z;
            
            % Curvas de velocidad (m/s)
            u_cono = f0_max./Z;
            u_puerto = fuerza_react./(1j*w.*Mmp);
            u_total = fuerza_react*1j.*w.*Cmb;
            u_fugas = fuerza_react./RL;
            
            % Curvas de aceleración (m/s^2)
            a_cono = 1j*w.*u_cono;
            a_puerto = 1j*w.*u_puerto;
            a_total = 1j*w.*u_total;
            a_fugas = 1j*w.*u_fugas;
            
            % Curvas de presión (Pa)
            presion_cono = abs(a_cono).*app.rho.*Sd/(2*pi);
            presion_puerto = abs(a_puerto).*app.rho.*Sd/(2*pi);
            presion_total = abs(a_total).*app.rho.*Sd/(2*pi);
            presion_fugas = abs(a_fugas).*app.rho.*Sd/(2*pi);
            
            % Curvas de SPL (dB)
            SPL_cono = 20*log10(presion_cono/20e-6);
            SPL_puerto = 20*log10(presion_puerto/20e-6);
            SPL_total = 20*log10(presion_total/20e-6);
            SPL_fugas = 20*log10(presion_fugas/20e-6);
            
            SPL.cono = SPL_cono;
            SPL.puerto = SPL_puerto;
            SPL.total = SPL_total;
            SPL.fugas = SPL_fugas;
            
            % Calculo de f3 y de rizado (pico y pico-valle)
            f3 = zeros(size(SPL_total,1),1);
            rizado = zeros(size(SPL_total,1),2);
            for i = 1:size(SPL_total,1)
                % f -3dB
                pos_f3 = find(SPL.total(i,:)<SPL.total(i,end)-3);
                f3(i) = f(pos_f3(end));
                
                % Rizado pico (1) y pico-valle (2)
                [~,pos_maxSPL] = max(SPL.total(i,:));
                rizado(i,1) = max(SPL.total(i,:)) - SPL.total(i,end);
                
                dif_SPL = diff(SPL.total(i,:)); pos_pico = find(dif_SPL<0); 
                if isempty(pos_pico)
                    rizado(i,2) = 0;
                else
                    pos_pico = pos_pico(1);
                    rizado(i,2) = max(SPL.total(i,pos_pico:end)) - min(SPL.total(i,pos_pico:end));
                end

                
                 %  rizado(i,2) = max(SPL.total(i,:)) - min(SPL.total(i,pos_maxSPL:end));
            end
            
            % Impedancia eléctrica
            Ze = abs(Re + Bl.^2./(Z-Bl.^2./Re));
            
            % Calculo de las dimensiones del puerto
            u_viento_max = 7.5/100*app.c; %velocidad máxima para evitar ruidos en el puerto
            u_MP_max = max(abs(u_puerto),[],2).*sqrt(2);
            S_puerto_min = u_MP_max.*Sd./u_viento_max;
            d_puerto = sqrt(4*S_puerto_min/pi);
            L_puerto = (app.c*d_puerto./(4*fb)).^2./(pi*Vb) - 0.52*d_puerto;
            
        end
        
        
        function [Vbmin] = calcula_Vb_validos(app,Vb,fb,rizado,f3,SPLobjetivo,QL,k)
            % Vb es el volumen indicado por el usuario en la UI
            
            altavoz_valido = 1;
            fb_fs = fb/app.TablaDB.Fs(k);
            alfa = (app.TablaDB.Vas(k)*10^-3)/Vb;
            
            %itera incrementando el valor de alfa (progresivamente cajas más pequeñas)
            %hasta que dejan de encontrarse altavoces que satisfacen las prestaciones
            %exigidas
            while altavoz_valido
                Vbmin = app.TablaDB.Vas(k)/alfa;
                alfa = alfa*1.02;
                [~,f3_est,rizado_est,~,SPL_max_est,~,~,~,~,~,~,~] = prestaciones_caja_abierta(app,alfa,fb_fs,QL,app.TablaDB(k,:),'normal');
                if (f3_est>f3) || (rizado_est(:,1)-rizado(1)>0) || (rizado_est(:,2)-rizado(2)>0) || (SPL_max_est<SPLobjetivo)
                    altavoz_valido = 0;
                end
            end
        end
        
        function [Index,Vb,fb] = escoge_mejor_altavoz(app,criterio,Vbmin,Vb_objetivo,fb,SPL_max,f3min,Lp)
            
            
            Vb = Vb_objetivo/1e3;
            Vbmin = Vbmin/1e3;
            
            switch criterio
                case 'MaxRang'
                    [~,Index] = max(fb(:,2)-fb(:,1));
                    fb = round(mean(fb(Index,:)));
                case 'Max_fb'
                    [fb,Index] = max(fb(:,2));
                case 'Min_fb'
                    [fb,Index] = min(fb(:,1));
                case 'ShortestPort'
                    [~,Index] = min(Lp);
                    %Dp = Dp(Index);
                    fb = fb(Index,2);
                case 'maxSPL'
                    [~,Index] = max(SPL_max);
                    %Dp = Dp(Index);
                    fb = fb(Index,3);
                case 'minf3'
                    [~,Index] = min(f3min);
                    %Dp = Dp(Index);
                    fb = fb(Index,4);
                case 'minVb'
                    [Vb,Index] = min(Vbmin);
                    fb = fb(Index,2);
                otherwise
                    Index = criterio;
            end
            
        end
        
        function saveResults(app)
            [file,path,ind] = uiputfile({...
                '*.txt','Text File (.txt)';
                '*.xls','Excel File (.xls)';
                '*.mat','M-file (.mat)'
                },'Save results','Available Speakers.txt');
            app.LoVEBASSUIFigure.Visible = 'off';
            app.LoVEBASSUIFigure.Visible = 'on';
            % Si se ha elegido una ruta
            if ischar(file)
                if app.pestana == 1
                    Speakers = sortrows(app.TablaAlignment);
                elseif app.pestana == 2
                    Speakers = sortrows(app.TablaParameters);
                end
                
                switch ind
                    case 1
                        writetable(Speakers,[path,file],'Delimiter','\t')
                    case 2
                        % Se incrementa el tamaño para incluir las unidades en la tabla
                        [hh,w]=size(Speakers);
                        a=cell(hh+2,w);
                        a(1,:) = Speakers.Properties.VariableNames;
                        a(2,:) = Speakers.Properties.VariableUnits; a(2,1)=a(1,1); a(1,1)={''};
                        a(3:end,:) = table2cell(Speakers);
                        Speakers2 = cell2table(a);
                        % Comprobacion de si existe el archivo y si existe se elimina
                        % Esto previene un bug por el que aparecen mas filas de las reales
                        if exist([path,file], 'file')==2
                            delete([path,file]);
                        end
                        % Crear excel
                        writetable(Speakers2,[path,file],'WriteVariableNames',0)
                    case 3
                        save([path,file],'Speakers')
                end
            end
        end
        
        function table = roundTable(app,table,dec)
            a = [];
            for i = 1:size(table,2)
                if isa(table{1,i},'double')
                    a = [a,i]; %#ok<AGROW>
                end
            end
            table{:,a} = round(table{:,a},dec);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Extra functions (research purpose): change to 'true'
            app.research = false;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if app.research
                % Pestaña seleccionada al arrancar
                app.pestana = 1;
                app.TabGroup.SelectedTab = app.TabGroup.Children(app.pestana);
            else
                % Pestaña seleccionada al arrancar
                app.pestana = 2;
                app.TabGroup.SelectedTab = app.TabGroup.Children(app.pestana);
                % Eliminar pestaña de alineamiento
                app.TabGroup.Children(1).Parent = [];
                % Ocultar ayuda
                app.HelpSC4QB3Menu.Visible = 'off';
            end
            
            % Se muestran los numeros de las tablas en formato shortG
            format shortG
            
            app.TabGroup.Visible = 'off';
            app.FileMenu.Visible = 'off';
            app.SpeakerDBMenu.Visible = 'off';
            app.HelpMenu.Visible = 'off';

            % Tabla altavoces
            app.ListSpeakers.Visible = 'off';
            app.ListSpeakers.Title = ...
                sprintf('Available speakers according to the specifications and conditions through the %s',app.TabGroup.SelectedTab.Title);

            %% ALINEAMIENTO
            if app.research
                % Desativar condiciones donde los valores son iguales a 0
                if app.SPL1WdBEditField.Value <= 0
                    app.SPL_1WMinorCheckBox_2.Enable = 'off';
                    app.SPL_1WMajorCheckBox.Enable = 'off';
                else
                    app.SPL_1WMinorCheckBox_2.Enable = 'on';
                    app.SPL_1WMajorCheckBox.Enable = 'on';
                end
                if app.SPLmaxEditFieldPot.Value <= 0
                    app.SPL_maxMajorCheckBox.Enable = 'off';
                    app.SPL_maxMinorCheckBox_2.Enable = 'off';
                else
                    app.SPL_maxMajorCheckBox.Enable = 'on';
                    app.SPL_maxMinorCheckBox_2.Enable = 'on';
                end
                if app.f3Field.Value <= 0
                    app.f3MinorCheckBox.Enable = 'off';
                    app.f3MajorCheckBox_2.Enable = 'off';
                else
                    app.f3MinorCheckBox.Enable = 'on';
                    app.f3MajorCheckBox_2.Enable = 'on';
                end
                if app.XmaxmmEditField.Value <= 0
                    app.XmaxMinorCheckBox.Enable = 'off';
                    app.XmaxMajorCheckBox_2.Enable = 'off';
                else
                    app.XmaxMinorCheckBox.Enable = 'on';
                    app.XmaxMajorCheckBox_2.Enable = 'on';
                end
                if app.SizeinchesEditField.Value <= 0
                    app.SdMajorCheckBox_2.Enable = 'off';
                    app.SdMinorCheckBox.Enable = 'off';
                else
                    app.SdMajorCheckBox_2.Enable = 'on';
                    app.SdMinorCheckBox.Enable = 'on';
                end
                
                % Paneles de alineamiento
                app.PestAlign.Visible = 'off';
            end
            %% CRITERIO PARAMETROS
            % Paneles del criterio de parametros
            app.TabGroup2.Visible = 'off';
            app.BarraCarga.Visible = 'off';
            app.ProcessedspeakersLabel.Visible = 'off';
            
            % Frecuencia
            app.f3Field.Enable = 'on';
            app.XmaxmmEditField.Enable = 'on';
            app.SPL1WdBEditField.Enable = 'on';
            app.SPLmaxEditFieldPot.Enable = 'on';
            
            %% DISENYO CUSTOM
            % Panel de warnings de disenyo custom
            app.WarningsTextArea.Visible = 'off';
            % Label de coeficiente de alineamiento
            app.AlignCoefCustom.Visible = 'off';
            app.AlignCoefCustomLabel.Visible = 'off';
            
            %% GENERAL
            % Constantes
            app.rho = app.rhofield.Value;
            app.c = app.cfield.Value;
            
            % Mensaje de bienvenida
            exit = 0;
            msg = sprintf(...
                ['Loudspeakers for Vented Enclosures: a Backwards Approach for Speaker Selection\n',...
                '\n',...
                ' Developed by:\n',...
                '  Francisco Sales Castells Ramón | fcastells@eln.upv.es\n',...
                '  Jose Manuel Requena Plens       | info@jmrplens.com\n',...
                '\n LoVE BASS 2019®']);
            title = 'Welcome';
            while exit == 0
                selection = uiconfirm(app.LoVEBASSUIFigure,msg,title,...
                    'Options',{'OK','Contact'},...
                    'DefaultOption',1,'CancelOption',1,'Icon',['include',filesep,'images',filesep,'logo-square.png']);
                if strcmp(selection,'Contact')
                    email = 'fcastells@eln.upv.es,info@jmrplens.com';
                    url = ['mailto:',email,'?subject=LoVE%20BASS%20App'];
                    web(url,'-browser')
                elseif strcmp(selection,'OK')
                    exit = 1;
                end
            end
            
            app.FileMenu.Visible = 'on';
            app.SpeakerDBMenu.Visible = 'on';
            app.HelpMenu.Visible = 'on';
            app.TabGroup.Visible = 'on';
        end

        % Close request function: LoVEBASSUIFigure
        function LoVEBASSUIFigureCloseRequest(app, event)
            % Devuelve el formato al formato por defecto
            format short
            delete(app)
        end

        % Value changed function: VblitersEditField
        function VblitersEditFieldValueChanged(app, event)
            value = app.VblitersEditField.Value;
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            app.InfoAlign.Text = 'Processing'; drawnow
            % Importa la base de datos de altavoces y realiza los calculos
            % de parametros y curvas
            error = ImportDB(app);
            
            if ~error
                % Buscar altavoces segun especificaciones y condiciones
                findSpeakers(app)
                
                % Ver tabla de altavoces
                app.ListSpeakers.Visible = 'on';
                
                app.InfoAlign.Text = 'Click on a speaker to see the details'; drawnow
            else
                app.InfoAlign.Text = 'Error';
            end
        end

        % Value changed function: f3Field
        function f3FieldValueChanged(app, event)
            if app.f3Field.Value <= 0
                app.f3MinorCheckBox.Enable = 'off';
                app.f3MajorCheckBox_2.Enable = 'off';
                app.f3MinorCheckBox.Value = 0;
                app.f3MajorCheckBox_2.Value = 0;
            else
                app.f3MinorCheckBox.Enable = 'on';
                app.f3MajorCheckBox_2.Enable = 'on';
            end
        end

        % Value changed function: XmaxmmEditField
        function XmaxmmEditFieldValueChanged(app, event)
if app.XmaxmmEditField.Value <= 0
                app.XmaxMinorCheckBox.Enable = 'off';
                app.XmaxMajorCheckBox_2.Enable = 'off';
            else
                app.XmaxMinorCheckBox.Enable = 'on';
                app.XmaxMajorCheckBox_2.Enable = 'on';
            end
        end

        % Value changed function: SPL1WdBEditField
        function SPL1WdBEditFieldValueChanged(app, event)
            if app.SPL1WdBEditField.Value <= 0
                app.SPL_1WMinorCheckBox_2.Enable = 'off';
                app.SPL_1WMajorCheckBox.Enable = 'off';
                app.SPL_1WMinorCheckBox_2.Value = 0;
                app.SPL_1WMajorCheckBox.Value = 0;
            else
                app.SPL_1WMinorCheckBox_2.Enable = 'on';
                app.SPL_1WMajorCheckBox.Enable = 'on';
            end
        end

        % Value changed function: SPLmaxEditFieldPot
        function SPLmaxEditFieldPotValueChanged(app, event)
            if app.SPLmaxEditFieldPot.Value <= 0
                app.SPL_maxMajorCheckBox.Enable = 'off';
                app.SPL_maxMinorCheckBox_2.Enable = 'off';
                app.SPL_maxMajorCheckBox.Value = 0;
                app.SPL_maxMinorCheckBox_2.Value = 0;
            else
                app.SPL_maxMajorCheckBox.Enable = 'on';
                app.SPL_maxMinorCheckBox_2.Enable = 'on';
            end
        end

        % Value changed function: rhofield
        function rhofieldValueChanged(app, event)
            app.rho = app.rhofield.Value;
            app.rhofield_2.Value = app.rho;
        end

        % Value changed function: cfield
        function cfieldValueChanged(app, event)
            app.c = app.cfield.Value;
            app.cfield_2.Value = app.c;
        end

        % Value changed function: SPL_1WMajorCheckBox
        function SPL_1WMajorCheckBoxValueChanged(app, event)
            value = app.SPL_1WMajorCheckBox.Value;
            
            if value == 1
                app.SPL_1WMinorCheckBox_2.Value = 0;
            end
        end

        % Value changed function: SPL_1WMinorCheckBox_2
        function SPL_1WMinorCheckBox_2ValueChanged(app, event)
            value = app.SPL_1WMinorCheckBox_2.Value;
            if value == 1
                app.SPL_1WMajorCheckBox.Value = 0;
            end
        end

        % Value changed function: SPL_maxMajorCheckBox
        function SPL_maxMajorCheckBoxValueChanged(app, event)
            value = app.SPL_maxMajorCheckBox.Value;
            if value == 1
                app.SPL_maxMinorCheckBox_2.Value = 0;
            end
        end

        % Value changed function: SPL_maxMinorCheckBox_2
        function SPL_maxMinorCheckBox_2ValueChanged(app, event)
            value = app.SPL_maxMinorCheckBox_2.Value;
            if value == 1
                app.SPL_maxMajorCheckBox.Value = 0;
            end
        end

        % Value changed function: f3MinorCheckBox
        function f3MinorCheckBoxValueChanged(app, event)
            value = app.f3MinorCheckBox.Value;
            if value == 1
                app.f3MajorCheckBox_2.Value = 0;
            end
        end

        % Value changed function: f3MajorCheckBox_2
        function f3MajorCheckBox_2ValueChanged(app, event)
            value = app.f3MajorCheckBox_2.Value;
            if value == 1
                app.f3MinorCheckBox.Value = 0;
            end
        end

        % Value changed function: VbMinorCheckBox
        function VbMinorCheckBoxValueChanged(app, event)
            value = app.VbMinorCheckBox.Value;
            if value == 1
                app.VbMajorCheckBox_2.Value = 0;
            end
        end

        % Value changed function: VbMajorCheckBox_2
        function VbMajorCheckBox_2ValueChanged(app, event)
            value = app.VbMajorCheckBox_2.Value;
            if value == 1
                app.VbMinorCheckBox.Value = 0;
            end
        end

        % Value changed function: XmaxMinorCheckBox
        function XmaxMinorCheckBoxValueChanged(app, event)
            value = app.XmaxMinorCheckBox.Value;
            if value == 1
                app.XmaxMajorCheckBox_2.Value = 0;
            end
        end

        % Value changed function: XmaxMajorCheckBox_2
        function XmaxMajorCheckBox_2ValueChanged(app, event)
            value = app.XmaxMajorCheckBox_2.Value;
            if value == 1
                app.XmaxMinorCheckBox.Value = 0;
            end
        end

        % Value changed function: SdMinorCheckBox
        function SdMinorCheckBoxValueChanged(app, event)
            value = app.SdMinorCheckBox.Value;
            if value == 1
                app.SdMajorCheckBox_2.Value = 0;
            end
        end

        % Value changed function: SdMajorCheckBox_2
        function SdMajorCheckBox_2ValueChanged(app, event)
            value = app.SdMajorCheckBox_2.Value;
            if value == 1
                app.SdMinorCheckBox.Value = 0;
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            app.ListSpeakers.Title = ...
                sprintf('Available speakers according to the specifications and conditions through the %s',selectedTab.Title);
            % Obtener el indice de la pestaña elegida
            a = 0;
            while ~exist('pestanaaux','var')
                a = a + 1;
                if strcmp(app.TabGroup.Children(a).Title,app.TabGroup.SelectedTab.Title)
                    pestanaaux = a;
                    if ~app.research
                        % Si se desactiva la primera pestaña el valor
                        % obtenido se debera incrementar en 1
                        pestanaaux = pestanaaux + 1;
                    end
                end
                
            end
            app.pestana = pestanaaux;
            switch app.pestana
                case 1
                    if ~isempty(app.TablaAlignment)
                        app.WarningsTextArea.Visible = 'off';
                        % Añadir checkbox
                        Tmp = table('Size',[height(app.TablaAlignment),1],'VariableTypes',{'logical'});
                        Tmp.Properties.VariableNames = {'Plot'};
                        % Tabla + checkbox en la UI
                        app.TableSpeakers.Data = sortrows([Tmp,app.TablaAlignment]);
                        app.TableSpeakers.ColumnName = [Tmp.Properties.VariableNames,app.TablaAlignment.Properties.VariableNames];
                        app.TableSpeakers.ColumnEditable(:,1) = true;
                        app.ListSpeakers.Visible = 'on';
                    else
                        app.WarningsTextArea.Visible = 'off';
                        app.ListSpeakers.Visible = 'off';
                    end
                    
                case 2
                    if ~isempty(app.TablaParameters)
                        app.WarningsTextArea.Visible = 'off';
                        % Añadir checkbox
                        Tmp = table('Size',[height(app.TablaParameters),1],'VariableTypes',{'logical'});
                        Tmp.Properties.VariableNames = {'Plot'};
                        % Tabla + checkbox en la UI
                        app.TableSpeakers.Data = sortrows([Tmp,app.TablaParameters]);
                        app.TableSpeakers.ColumnName = [Tmp.Properties.VariableNames,app.TablaParameters.Properties.VariableNames];
                        app.TableSpeakers.ColumnEditable(:,1) = true;
                        app.ListSpeakers.Visible = 'on';
                    else
                        app.WarningsTextArea.Visible = 'off';
                        app.ListSpeakers.Visible = 'off';
                    end
                    
                case 3
                    app.ListSpeakers.Visible = 'off';
                    app.pestana = 3;
                    calcCustom(app)
                    app.WarningsTextArea.Visible = 'on';
                    
            end
        end

        % Button pushed function: RunButton_Criteria2
        function RunButton_Criteria2Pushed(app, event)
            % Cargar y calcular tabla completa
            error = ImportDB(app);
            
            if ~error
                % Inicializar la barra de carga
                app.TabGroup2.Visible = 'off';
                app.BarraCarga.Visible = 'on'; app.ProcessedspeakersLabel.Visible = 'on';
                app.BarraCarga.Limits = [0,height(app.TablaDB)];
                % Calculo
                error2 = calcParametersCriter(app);
                % Visualizar resultados
                app.BarraCarga.Visible = 'off'; app.ProcessedspeakersLabel.Visible = 'off';
                if ~error2
                    app.TabGroup2.Visible = 'on';
                    app.ListSpeakers.Visible = 'on';
                end
            end
        end

        % Menu selected function: ExportresultlistMenu
        function ExportresultlistMenuSelected(app, event)
            saveResults(app)
        end

        % Value changed function: rhofield_2
        function rhofield_2ValueChanged(app, event)
            app.rho = app.rhofield_2.Value;
            app.rhofield.Value = app.rho;
            app.rhofield_3.Value = app.rho;
        end

        % Value changed function: cfield_2
        function cfield_2ValueChanged(app, event)
            app.c = app.cfield_2.Value;
            app.cfield.Value = app.c;
            app.cfield_3.Value = app.c;
        end

        % Menu selected function: SelectdatabaseMenu
        function SelectdatabaseMenuSelected(app, event)
            [file,path] = uigetfile('*.xlsx');
            if ischar(file)
                app.BaseDatos = fullfile(path,file);
            end
            app.LoVEBASSUIFigure.Visible = 'off';
            app.LoVEBASSUIFigure.Visible = 'on';
        end

        % Menu selected function: ViewactualdatabaseMenu
        function ViewactualdatabaseMenuSelected(app, event)
            open(app.BaseDatos)
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            
            exit = 0;
            msg = sprintf(...
                ['Loudspeakers for Vented Enclosures: a Backwards Approach for Speaker Selection\n',...
                '\n',...
                ' Developed by:\n',...
                '  Francisco Castells Ramón        | fcastells@eln.upv.es\n',...
                '  Jose Manuel Requena Plens       | info@jmrplens.com\n',...
                '\n LoVE BASS 2019®']);
            title = 'Welcome';
            while exit == 0
                selection = uiconfirm(app.LoVEBASSUIFigure,msg,title,...
                    'Options',{'OK','Contact'},...
                    'DefaultOption',1,'CancelOption',1,'Icon',['include',filesep,'images',filesep,'logo-square.png']);
                if strcmp(selection,'Contact')
                    email = 'fcastells@eln.upv.es,info@jmrplens.com';
                    url = ['mailto:',email,'?subject=LoVE%20BASS%20App'];
                    web(url,'-browser')
                elseif strcmp(selection,'OK')
                    exit = 1;
                end
            end
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            if sum(app.TableSpeakers.Data.Plot)>0
            plotTable(app)
            end
        end

        % Cell selection callback: TableSpeakers
        function TableSpeakersCellSelection(app, event)
            app.InfoAlign.Text = 'Loading, please wait'; drawnow;
            indices = event.Indices;
            app.AltSelect = indices(1);
            
            % Si se está en la pestaña de alineamiento y la celda donde se ha
            % hecho clic no es la de plot, ejecuta la representacion de los datos
            if app.pestana == 1 && indices(2)~=1
                plotCurvesAligSpeaker(app)
                app.InfoAlign.Visible = 'off';
                app.PestAlign.Visible = 'on';
            end
        end

        % Value changed function: SizeinchesEditField
        function SizeinchesEditFieldValueChanged(app, event)

            if app.SizeinchesEditField.Value <= 0
                app.SdMajorCheckBox_2.Enable = 'off';
                app.SdMinorCheckBox.Enable = 'off';
                app.SdMajorCheckBox_2.Value = 0;
                app.SdMinorCheckBox.Value = 0;
            else
                app.SdMajorCheckBox_2.Enable = 'on';
                app.SdMinorCheckBox.Enable = 'on';
            end
        end

        % Value changed function: f_bMaxSPL
        function f_bMaxSPLValueChanged(app, event)
            adjustResults(app,... % General
                app.MaxSPLplotSPL,... % Eje de SPL
                app.MaxSPLplotXmax,... % Eje de Xmax
                1,... % Tipo (1=Max SPL,2=Min f3, 3=Min Vb, 4=Min Port)
                app.VbMaxSPL.Value,app.f_bMaxSPL.Value,app.SPLMaxSPL.Value) % Variables de usuario
        end

        % Value changed function: VbMaxSPL
        function VbMaxSPLValueChanged(app, event)
            adjustResults(app,... % General
                app.MaxSPLplotSPL,... % Eje de SPL
                app.MaxSPLplotXmax,... % Eje de Xmax
                1,... % Tipo (1=Max SPL,2=Min f3, 3=Min Vb, 4=Min Port)
                app.VbMaxSPL.Value,app.f_bMaxSPL.Value,app.SPLMaxSPL.Value) % Variables de usuario
        end

        % Value changed function: SPLMaxSPL
        function SPLMaxSPLValueChanged(app, event)
            adjustResults(app,... % General
                app.MaxSPLplotSPL,... % Eje de SPL
                app.MaxSPLplotXmax,... % Eje de Xmax
                1,... % Tipo (1=Max SPL,2=Min f3, 3=Min Vb, 4=Min Port)
                app.VbMaxSPL.Value,app.f_bMaxSPL.Value,app.SPLMaxSPL.Value) % Variables de usuario
        end

        % Button pushed function: ResetButtonMaxSPL
        function ResetButtonMaxSPLPushed(app, event)
            
            % Extraer altavoz recomendado para maximo SPL
            tabla = app.TablaParameters(app.IndexRecom(1),:);
            tablaCurv = app.TablaDBCurvParam(app.IndexRecom(1),:);
            
            plotSPLcurves(app,app.MaxSPLplotSPL,tabla,tablaCurv,1)
            plotXmaxcurves(app,app.MaxSPLplotXmax,tabla,tablaCurv)
            
            % Actualizar datos en las otras casillas
            app.f_bMaxSPL.Value = round(tabla.Fb,1);
            app.VbMaxSPL.Value = round(tabla.Vb,1);
            app.SPLMaxSPL.Value = round(tabla.SPLmax,1);
        end

        % Value changed function: f_bMinf3
        function f_bMinf3ValueChanged(app, event)
            adjustResults(app,... % General
                app.Minf3plotSPL,... % Eje de SPL
                app.Minf3plotXmax,... % Eje de Xmax
                2,... % Tipo (1=Max SPL,2=Min f3, 3=Min Vb, 4=Min Port)
                app.VbMinf3.Value,app.f_bMinf3.Value,app.SPLMinf3.Value) % Variables de usuario
        end

        % Value changed function: VbMinf3
        function VbMinf3ValueChanged(app, event)
            adjustResults(app,... % General
                app.Minf3plotSPL,... % Eje de SPL
                app.Minf3plotXmax,... % Eje de Xmax
                2,... % Tipo (1=Max SPL,2=Min f3, 3=Min Vb, 4=Min Port)
                app.VbMinf3.Value,app.f_bMinf3.Value,app.SPLMinf3.Value) % Variables de usuario
        end

        % Value changed function: SPLMinf3
        function SPLMinf3ValueChanged(app, event)
            adjustResults(app,... % General
                app.Minf3plotSPL,... % Eje de SPL
                app.Minf3plotXmax,... % Eje de Xmax
                2,... % Tipo (1=Max SPL,2=Min f3, 3=Min Vb, 4=Min Port)
                app.VbMinf3.Value,app.f_bMinf3.Value,app.SPLMinf3.Value) % Variables de usuario
        end

        % Button pushed function: ResetButtonMinf3
        function ResetButtonMinf3Pushed(app, event)
            % Extraer altavoz recomendado para maximo SPL
            tabla = app.TablaParameters(app.IndexRecom(2),:);
            tablaCurv = app.TablaDBCurvParam(app.IndexRecom(2),:);
            
            plotSPLcurves(app,app.Minf3plotSPL,tabla,tablaCurv,1)
            plotXmaxcurves(app,app.Minf3plotXmax,tabla,tablaCurv)
            
            % Actualizar datos en las otras casillas
            app.f_bMinf3.Value = round(tabla.Fb,1);
            app.VbMinf3.Value = round(tabla.Vb,1);
            app.SPLMinf3.Value = round(tabla.SPLmax,1);
        end

        % Value changed function: f_bMinVb
        function f_bMinVbValueChanged(app, event)
            adjustResults(app,... % General
                app.MinVbplotSPL,... % Eje de SPL
                app.MinVbplotXmax,... % Eje de Xmax
                3,... % Tipo (1=Max SPL,2=Min f3, 3=Min Vb, 4=Min Port)
                app.VbMinVb.Value,app.f_bMinVb.Value,app.SPLMinVb.Value) % Variables de usuario
        end

        % Value changed function: VbMinVb
        function VbMinVbValueChanged(app, event)
            adjustResults(app,... % General
                app.MinVbplotSPL,... % Eje de SPL
                app.MinVbplotXmax,... % Eje de Xmax
                3,... % Tipo (1=Max SPL,2=Min f3, 3=Min Vb, 4=Min Port)
                app.VbMinVb.Value,app.f_bMinVb.Value,app.SPLMinVb.Value) % Variables de usuario
        end

        % Value changed function: SPLMinVb
        function SPLMinVbValueChanged(app, event)
            adjustResults(app,... % General
                app.MinVbplotSPL,... % Eje de SPL
                app.MinVbplotXmax,... % Eje de Xmax
                3,... % Tipo (1=Max SPL,2=Min f3, 3=Min Vb, 4=Min Port)
                app.VbMinVb.Value,app.f_bMinVb.Value,app.SPLMinVb.Value) % Variables de usuario
        end

        % Button pushed function: ResetButtonMinVb
        function ResetButtonMinVbPushed(app, event)
            % Extraer altavoz recomendado para maximo SPL
            tabla = app.TablaParameters(app.IndexRecom(3),:);
            tablaCurv = app.TablaDBCurvParam(app.IndexRecom(3),:);
            
            plotSPLcurves(app,app.MinVbplotSPL,tabla,tablaCurv,1)
            plotXmaxcurves(app,app.MinVbplotXmax,tabla,tablaCurv)
            
            % Actualizar datos en las otras casillas
            app.f_bMinVb.Value = round(tabla.Fb,1);
            app.VbMinVb.Value = round(tabla.Vb,1);
            app.SPLMinVb.Value = round(tabla.SPLmax,1);
        end

        % Value changed function: f_bMinPort
        function f_bMinPortValueChanged(app, event)
            adjustResults(app,... % General
                app.MinPortplotSPL,... % Eje de SPL
                app.MinPortplotXmax,... % Eje de Xmax
                4,... % Tipo (1=Max SPL,2=Min f3, 3=Min Vb, 4=Min Port)
                app.VbMinPort.Value,app.f_bMinPort.Value,app.SPLMinPort.Value) % Variables de usuario
        end

        % Value changed function: VbMinPort
        function VbMinPortValueChanged(app, event)
            adjustResults(app,... % General
                app.MinPortplotSPL,... % Eje de SPL
                app.MinPortplotXmax,... % Eje de Xmax
                4,... % Tipo (1=Max SPL,2=Min f3, 3=Min Vb, 4=Min Port)
                app.VbMinPort.Value,app.f_bMinPort.Value,app.SPLMinPort.Value) % Variables de usuario
        end

        % Value changed function: SPLMinPort
        function SPLMinPortValueChanged(app, event)
            adjustResults(app,... % General
                app.MinPortplotSPL,... % Eje de SPL
                app.MinPortplotXmax,... % Eje de Xmax
                4,... % Tipo (1=Max SPL,2=Min f3, 3=Min Vb, 4=Min Port)
                app.VbMinPort.Value,app.f_bMinPort.Value,app.SPLMinPort.Value) % Variables de usuario
        end

        % Button pushed function: ResetButtonMinPort
        function ResetButtonMinPortPushed(app, event)
            % Extraer altavoz recomendado para maximo SPL
            tabla = app.TablaParameters(app.IndexRecom(4),:);
            tablaCurv = app.TablaDBCurvParam(app.IndexRecom(4),:);
            
            plotSPLcurves(app,app.MinPortplotSPL,tabla,tablaCurv,1)
            plotXmaxcurves(app,app.MinPortplotXmax,tabla,tablaCurv)
            
            % Actualizar datos en las otras casillas
            app.f_bMinPort.Value = round(tabla.Fb,1);
            app.VbMinPort.Value = round(tabla.Vb,1);
            app.SPLMinPort.Value = round(tabla.SPLmax,1);
        end

        % Callback function
        function RunCustomButtonPushed(app, event)
            calcCustom(app)
        end

        % Value changed function: rhofield_3
        function rhofield_3ValueChanged(app, event)
            app.rho = app.rhofield_3.Value;
            app.rhofield_2.Value = app.rho;
            app.rhofield.Value = app.rho;
            calcCustom(app)
        end

        % Value changed function: cfield_3
        function cfield_3ValueChanged(app, event)
            app.c = app.cfield_3.Value;
            app.cfield_2.Value = app.c;
            app.cfield.Value = app.c;
            calcCustom(app)
        end

        % Value changed function: VbCustom
        function VbCustomValueChanged(app, event)
           calcCustom(app)
        end

        % Value changed function: f3Custom
        function f3CustomValueChanged(app, event)
            calcCustom(app)
        end

        % Value changed function: SPLmaxCustom
        function SPLmaxCustomValueChanged(app, event)
            calcCustom(app)
        end

        % Value changed function: SizeCustom
        function SizeCustomValueChanged(app, event)
            calcCustom(app)
        end

        % Value changed function: ReCustom
        function ReCustomValueChanged(app, event)
            calcCustom(app)
        end

        % Value changed function: QL_Custom
        function QL_CustomValueChanged(app, event)
            calcCustom(app)
        end

        % Value changed function: AlignCoefCustom
        function AlignCoefCustomValueChanged(app, event)
            calcCustom(app)
        end

        % Value changed function: AlignmenttypeDropDown
        function AlignmenttypeDropDownValueChanged(app, event)
            value = app.AlignmenttypeDropDown.Value;
            switch value
                case 'B4'
                    app.AlignCoefCustom.Visible = 'off';
                    app.AlignCoefCustomLabel.Visible = 'off';
                case 'QB3'
                    app.AlignCoefCustom.Visible = 'on';
                    app.AlignCoefCustomLabel.Visible = 'on';
                    app.AlignCoefCustomLabel.Text = 'B';
                case 'C4'
                    app.AlignCoefCustom.Visible = 'on';
                    app.AlignCoefCustomLabel.Visible = 'on';
                    app.AlignCoefCustomLabel.Text = 'Ripple (dB)';
            end   
            calcCustom(app) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoVEBASSUIFigure = uifigure('Visible', 'off');
            app.LoVEBASSUIFigure.Name = 'LoVE BASS';
            app.LoVEBASSUIFigure.CloseRequestFcn = createCallbackFcn(app, @LoVEBASSUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.LoVEBASSUIFigure);
            app.FileMenu.Text = 'File';
            app.OpensesionMenu = uimenu(app.FileMenu);
            app.OpensesionMenu.Enable = 'off';
            app.OpensesionMenu.Text = 'Open sesion...';
            app.SavesessionMenu = uimenu(app.FileMenu);
            app.SavesessionMenu.Enable = 'off';
            app.SavesessionMenu.Text = 'Save session...';
            app.ExportresultlistMenu = uimenu(app.FileMenu);
            app.ExportresultlistMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportresultlistMenuSelected, true);
            app.ExportresultlistMenu.Text = 'Export result list';
            app.LanguageMenu = uimenu(app.FileMenu);
            app.LanguageMenu.Text = 'Language';
            app.EnglishMenu = uimenu(app.LanguageMenu);
            app.EnglishMenu.Text = 'English';
            app.SpanishMenu = uimenu(app.LanguageMenu);
            app.SpanishMenu.Enable = 'off';
            app.SpanishMenu.Text = 'Spanish';
            app.SpeakerDBMenu = uimenu(app.LoVEBASSUIFigure);
            app.SpeakerDBMenu.Text = 'Speaker DB';
            app.SelectdatabaseMenu = uimenu(app.SpeakerDBMenu);
            app.SelectdatabaseMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectdatabaseMenuSelected, true);
            app.SelectdatabaseMenu.Text = 'Select database...';
            app.ViewactualdatabaseMenu = uimenu(app.SpeakerDBMenu);
            app.ViewactualdatabaseMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewactualdatabaseMenuSelected, true);
            app.ViewactualdatabaseMenu.Text = 'View actual database';
            app.HelpMenu = uimenu(app.LoVEBASSUIFigure);
            app.HelpMenu.Text = 'Help';
            app.HelpSC4QB3Menu = uimenu(app.HelpMenu);
            app.HelpSC4QB3Menu.Enable = 'off';
            app.HelpSC4QB3Menu.Text = 'Help SC4/QB3';
            app.HelpforDriverselectionfromdatabaseMenu = uimenu(app.HelpMenu);
            app.HelpforDriverselectionfromdatabaseMenu.Enable = 'off';
            app.HelpforDriverselectionfromdatabaseMenu.Text = 'Help for "Driver selection from database"';
            app.HelpforDefinitionofparametersforcustomdriverdesignMenu = uimenu(app.HelpMenu);
            app.HelpforDefinitionofparametersforcustomdriverdesignMenu.Enable = 'off';
            app.HelpforDefinitionofparametersforcustomdriverdesignMenu.Text = 'Help for "Definition of parameters for custom driver design"';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About...';
            app.ListSpeakers = uipanel(app.LoVEBASSUIFigure);
            app.ListSpeakers.Title = 'Available speakers according to the specifications and conditions through the ... criterion';
            app.TableSpeakers = uitable(app.ListSpeakers);
            app.TableSpeakers.ColumnName = {''; ''};
            app.TableSpeakers.RowName = {};
            app.TableSpeakers.ColumnEditable = [true false];
            app.TableSpeakers.CellSelectionCallback = createCallbackFcn(app, @TableSpeakersCellSelection, true);
            app.PlotButton = uibutton(app.ListSpeakers, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.TabGroup = uitabgroup(app.LoVEBASSUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SC4QB3AlignmentcriterionTab = uitab(app.TabGroup);
            app.SC4QB3AlignmentcriterionTab.Title = 'SC4/QB3 Alignment criterion';
            app.RunButton = uibutton(app.SC4QB3AlignmentcriterionTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ConstantsPanel = uipanel(app.SC4QB3AlignmentcriterionTab);
            app.ConstantsPanel.Title = 'Constants';
            app.Airdensitykgm3Label = uilabel(app.ConstantsPanel);
            app.Airdensitykgm3Label.Text = 'Air density (kg/m3)';
            app.rhofield = uieditfield(app.ConstantsPanel, 'numeric');
            app.rhofield.Limits = [1 Inf];
            app.rhofield.ValueDisplayFormat = '%.2f';
            app.rhofield.ValueChangedFcn = createCallbackFcn(app, @rhofieldValueChanged, true);
            app.rhofield.Value = 1.18;
            app.SoundspeedmsLabel = uilabel(app.ConstantsPanel);
            app.SoundspeedmsLabel.Text = 'Sound speed (m/s)';
            app.cfield = uieditfield(app.ConstantsPanel, 'numeric');
            app.cfield.Limits = [1 Inf];
            app.cfield.ValueDisplayFormat = '%.1f';
            app.cfield.ValueChangedFcn = createCallbackFcn(app, @cfieldValueChanged, true);
            app.cfield.Value = 345;
            app.ConditionsforspeakerssearchPanel = uipanel(app.SC4QB3AlignmentcriterionTab);
            app.ConditionsforspeakerssearchPanel.Title = 'Conditions for speakers search';
            app.SPL_maxMajorCheckBox = uicheckbox(app.ConditionsforspeakerssearchPanel);
            app.SPL_maxMajorCheckBox.ValueChangedFcn = createCallbackFcn(app, @SPL_maxMajorCheckBoxValueChanged, true);
            app.SPL_maxMajorCheckBox.Text = '> SPL_max';
            app.SPL_maxMajorCheckBox.Value = true;
            app.SPL_1WMajorCheckBox = uicheckbox(app.ConditionsforspeakerssearchPanel);
            app.SPL_1WMajorCheckBox.ValueChangedFcn = createCallbackFcn(app, @SPL_1WMajorCheckBoxValueChanged, true);
            app.SPL_1WMajorCheckBox.Text = '> SPL_1W';
            app.f3MinorCheckBox = uicheckbox(app.ConditionsforspeakerssearchPanel);
            app.f3MinorCheckBox.ValueChangedFcn = createCallbackFcn(app, @f3MinorCheckBoxValueChanged, true);
            app.f3MinorCheckBox.Text = '< f3';
            app.f3MinorCheckBox.Value = true;
            app.VbMinorCheckBox = uicheckbox(app.ConditionsforspeakerssearchPanel);
            app.VbMinorCheckBox.ValueChangedFcn = createCallbackFcn(app, @VbMinorCheckBoxValueChanged, true);
            app.VbMinorCheckBox.Text = '< Vb';
            app.VbMinorCheckBox.Value = true;
            app.XmaxMinorCheckBox = uicheckbox(app.ConditionsforspeakerssearchPanel);
            app.XmaxMinorCheckBox.ValueChangedFcn = createCallbackFcn(app, @XmaxMinorCheckBoxValueChanged, true);
            app.XmaxMinorCheckBox.Text = '< Xmax';
            app.SdMinorCheckBox = uicheckbox(app.ConditionsforspeakerssearchPanel);
            app.SdMinorCheckBox.ValueChangedFcn = createCallbackFcn(app, @SdMinorCheckBoxValueChanged, true);
            app.SdMinorCheckBox.Text = '< Size';
            app.XmaxMajorCheckBox_2 = uicheckbox(app.ConditionsforspeakerssearchPanel);
            app.XmaxMajorCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @XmaxMajorCheckBox_2ValueChanged, true);
            app.XmaxMajorCheckBox_2.Text = '> Xmax';
            app.SdMajorCheckBox_2 = uicheckbox(app.ConditionsforspeakerssearchPanel);
            app.SdMajorCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @SdMajorCheckBox_2ValueChanged, true);
            app.SdMajorCheckBox_2.Text = '> Size';
            app.VbMajorCheckBox_2 = uicheckbox(app.ConditionsforspeakerssearchPanel);
            app.VbMajorCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @VbMajorCheckBox_2ValueChanged, true);
            app.VbMajorCheckBox_2.Text = '> Vb';
            app.f3MajorCheckBox_2 = uicheckbox(app.ConditionsforspeakerssearchPanel);
            app.f3MajorCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @f3MajorCheckBox_2ValueChanged, true);
            app.f3MajorCheckBox_2.Text = '> f3';
            app.SPL_maxMinorCheckBox_2 = uicheckbox(app.ConditionsforspeakerssearchPanel);
            app.SPL_maxMinorCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @SPL_maxMinorCheckBox_2ValueChanged, true);
            app.SPL_maxMinorCheckBox_2.Text = '< SPL_max';
            app.SPL_1WMinorCheckBox_2 = uicheckbox(app.ConditionsforspeakerssearchPanel);
            app.SPL_1WMinorCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @SPL_1WMinorCheckBox_2ValueChanged, true);
            app.SPL_1WMinorCheckBox_2.Text = '< SPL_1W';
            app.DesiredparametersPanel = uipanel(app.SC4QB3AlignmentcriterionTab);
            app.DesiredparametersPanel.Title = 'Desired parameters';
            app.SPL_1WdBLabel = uilabel(app.DesiredparametersPanel);
            app.SPL_1WdBLabel.Text = 'SPL_1W (dB)';
            app.SPL1WdBEditField = uieditfield(app.DesiredparametersPanel, 'numeric');
            app.SPL1WdBEditField.Limits = [0 194];
            app.SPL1WdBEditField.ValueDisplayFormat = '%g';
            app.SPL1WdBEditField.ValueChangedFcn = createCallbackFcn(app, @SPL1WdBEditFieldValueChanged, true);
            app.SPL_maxdBLabel = uilabel(app.DesiredparametersPanel);
            app.SPL_maxdBLabel.Text = 'SPL_max (dB)';
            app.SPLmaxEditFieldPot = uieditfield(app.DesiredparametersPanel, 'numeric');
            app.SPLmaxEditFieldPot.Limits = [0 194];
            app.SPLmaxEditFieldPot.ValueDisplayFormat = '%g';
            app.SPLmaxEditFieldPot.ValueChangedFcn = createCallbackFcn(app, @SPLmaxEditFieldPotValueChanged, true);
            app.SPLmaxEditFieldPot.Value = 110;
            app.f3HzEditFieldLabel = uilabel(app.DesiredparametersPanel);
            app.f3HzEditFieldLabel.Text = 'f3 (Hz)';
            app.f3Field = uieditfield(app.DesiredparametersPanel, 'numeric');
            app.f3Field.Limits = [0 Inf];
            app.f3Field.ValueDisplayFormat = '%g';
            app.f3Field.ValueChangedFcn = createCallbackFcn(app, @f3FieldValueChanged, true);
            app.f3Field.Value = 80;
            app.VblitersLabel = uilabel(app.DesiredparametersPanel);
            app.VblitersLabel.Text = 'Vb (liters)*';
            app.VblitersEditField = uieditfield(app.DesiredparametersPanel, 'numeric');
            app.VblitersEditField.Limits = [0.1 Inf];
            app.VblitersEditField.ValueDisplayFormat = '%g';
            app.VblitersEditField.ValueChangedFcn = createCallbackFcn(app, @VblitersEditFieldValueChanged, true);
            app.VblitersEditField.Value = 40;
            app.XmaxmmEditFieldLabel = uilabel(app.DesiredparametersPanel);
            app.XmaxmmEditFieldLabel.Text = 'Xmax (mm)';
            app.XmaxmmEditField = uieditfield(app.DesiredparametersPanel, 'numeric');
            app.XmaxmmEditField.Limits = [0 Inf];
            app.XmaxmmEditField.ValueDisplayFormat = '%g';
            app.XmaxmmEditField.ValueChangedFcn = createCallbackFcn(app, @XmaxmmEditFieldValueChanged, true);
            app.SizeinchesLabel_2 = uilabel(app.DesiredparametersPanel);
            app.SizeinchesLabel_2.Text = 'Size (inches)';
            app.SizeinchesEditField = uieditfield(app.DesiredparametersPanel, 'numeric');
            app.SizeinchesEditField.Limits = [0 Inf];
            app.SizeinchesEditField.ValueDisplayFormat = '%g';
            app.SizeinchesEditField.ValueChangedFcn = createCallbackFcn(app, @SizeinchesEditFieldValueChanged, true);
            app.IndicateonlytheonesyouwantnotallarenecessaryLabel = uilabel(app.DesiredparametersPanel);
            app.IndicateonlytheonesyouwantnotallarenecessaryLabel.Text = {'Indicate only the ones you want, '; 'not all are necessary. '; '*Vb is needed always.'; ''; 'These parameters are those that will be'; 'obtained with the speaker + box set'; ''};
            app.PestAlign = uitabgroup(app.SC4QB3AlignmentcriterionTab);
            app.GraphicresultsTab = uitab(app.PestAlign);
            app.GraphicresultsTab.Title = 'Graphic results';
            app.plotXmax = uiaxes(app.GraphicresultsTab);
            xlabel(app.plotXmax, 'X')
            ylabel(app.plotXmax, 'Y')
            app.plotSPL = uiaxes(app.GraphicresultsTab);
            title(app.plotSPL, 'Title')
            xlabel(app.plotSPL, 'X')
            ylabel(app.plotSPL, 'Y')
            app.ParameterresultsTab = uitab(app.PestAlign);
            app.ParameterresultsTab.Title = 'Parameter results';
            app.UIAxes = uiaxes(app.ParameterresultsTab);
            title(app.UIAxes, 'Run the app')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.AlignmentvaluesPanel = uipanel(app.ParameterresultsTab);
            app.AlignmentvaluesPanel.Title = 'Alignment values';
            app.TableValues = uitable(app.AlignmentvaluesPanel);
            app.TableValues.ColumnName = {''};
            app.TableValues.RowName = {'alpha'; 'h'; 'q'};
            app.ParametersresultsPanel = uipanel(app.ParameterresultsTab);
            app.ParametersresultsPanel.Title = 'Parameters results';
            app.ParamResults = uitable(app.ParametersresultsPanel);
            app.ParamResults.ColumnName = {'Values'; 'Units'};
            app.ParamResults.RowName = {'fs'; 'f3'; 'fb'; 'Pe_max'; 'SPL_max'; 'SPL_1W'; 'Vb'; 'Qts'; 'Xmax'; 'D_p'; 'L_p'};
            app.InfoAlign = uilabel(app.SC4QB3AlignmentcriterionTab);
            app.InfoAlign.Text = 'Click on ''run'' and click on a speaker to see the details';
            app.DriverselectionfromdatabaseTab = uitab(app.TabGroup);
            app.DriverselectionfromdatabaseTab.Title = 'Driver selection from database';
            app.InputParametersPanel = uipanel(app.DriverselectionfromdatabaseTab);
            app.InputParametersPanel.Title = 'Input Parameters';
            app.VblitersEditField_2Label = uilabel(app.InputParametersPanel);
            app.VblitersEditField_2Label.Text = 'Vb (liters)';
            app.VblitersEditField_2 = uieditfield(app.InputParametersPanel, 'numeric');
            app.VblitersEditField_2.Limits = [0 Inf];
            app.VblitersEditField_2.ValueDisplayFormat = '%g';
            app.VblitersEditField_2.Value = 50;
            app.LFboostdBEditFieldLabel = uilabel(app.InputParametersPanel);
            app.LFboostdBEditFieldLabel.Text = 'LF boost (dB)';
            app.LFboostdBEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.LFboostdBEditField.Limits = [0 Inf];
            app.LFboostdBEditField.ValueDisplayFormat = '%g';
            app.LFboostdBEditField.Value = 0.5;
            app.f3HzEditFieldLabel_2 = uilabel(app.InputParametersPanel);
            app.f3HzEditFieldLabel_2.Text = 'f3 (Hz)';
            app.f3HzEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.f3HzEditField.Limits = [0 Inf];
            app.f3HzEditField.ValueDisplayFormat = '%g';
            app.f3HzEditField.Value = 60;
            app.SPLmaxdBEditFieldLabel = uilabel(app.InputParametersPanel);
            app.SPLmaxdBEditFieldLabel.Text = 'SPLmax (dB)';
            app.SPLmaxdBEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.SPLmaxdBEditField.Limits = [0 Inf];
            app.SPLmaxdBEditField.ValueDisplayFormat = '%g';
            app.SPLmaxdBEditField.Value = 105;
            app.SizeinchesLabel = uilabel(app.InputParametersPanel);
            app.SizeinchesLabel.Text = 'Size (inches)';
            app.SizeEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.SizeEditField.Limits = [0 Inf];
            app.SizeEditField.ValueDisplayFormat = '%g';
            app.SizeEditField.Value = 15;
            app.EBPEditFieldLabel = uilabel(app.InputParametersPanel);
            app.EBPEditFieldLabel.Text = 'EBP';
            app.EBPEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.EBPEditField.Limits = [0 Inf];
            app.EBPEditField.ValueDisplayFormat = '%g';
            app.EBPEditField.Value = 70;
            app.RippledBEditFieldLabel = uilabel(app.InputParametersPanel);
            app.RippledBEditFieldLabel.Text = 'Ripple (dB)';
            app.RippledBEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.RippledBEditField.Limits = [0 Inf];
            app.RippledBEditField.ValueDisplayFormat = '%g';
            app.RippledBEditField.Value = 1;
            app.QlleakageQfactorLabel = uilabel(app.InputParametersPanel);
            app.QlleakageQfactorLabel.Text = 'Ql (leakage Q factor)';
            app.QlEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.QlEditField.Limits = [0 Inf];
            app.QlEditField.ValueDisplayFormat = '%g';
            app.QlEditField.Value = 7;
            app.maximumLabel = uilabel(app.InputParametersPanel);
            app.maximumLabel.Text = 'maximum';
            app.maximumLabel_2 = uilabel(app.InputParametersPanel);
            app.maximumLabel_2.Text = 'maximum';
            app.maximumLabel_3 = uilabel(app.InputParametersPanel);
            app.maximumLabel_3.Text = 'maximum';
            app.minimumLabel = uilabel(app.InputParametersPanel);
            app.minimumLabel.Text = 'minimum';
            app.minimumLabel_2 = uilabel(app.InputParametersPanel);
            app.minimumLabel_2.Text = 'minimum';
            app.maximumLabel_4 = uilabel(app.InputParametersPanel);
            app.maximumLabel_4.Text = 'maximum';
            app.minimumLabel_3 = uilabel(app.InputParametersPanel);
            app.minimumLabel_3.Text = 'minimum';
            app.GoalperformanceoftheVentedEnclosureLabel = uilabel(app.InputParametersPanel);
            app.GoalperformanceoftheVentedEnclosureLabel.Text = 'Goal performance of the Vented Enclosure ';
            app.DriverrestrictionsLabel = uilabel(app.InputParametersPanel);
            app.DriverrestrictionsLabel.Text = 'Driver restrictions';
            app.RunButton_Criteria2 = uibutton(app.DriverselectionfromdatabaseTab, 'push');
            app.RunButton_Criteria2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_Criteria2Pushed, true);
            app.RunButton_Criteria2.Text = 'Run';
            app.TabGroup2 = uitabgroup(app.DriverselectionfromdatabaseTab);
            app.MaximalSPLTab = uitab(app.TabGroup2);
            app.MaximalSPLTab.Title = 'Maximal SPL';
            app.MaxSPLplotSPL = uiaxes(app.MaximalSPLTab);
            title(app.MaxSPLplotSPL, 'Title')
            xlabel(app.MaxSPLplotSPL, 'X')
            ylabel(app.MaxSPLplotSPL, 'Y')
            app.MaxSPLplotXmax = uiaxes(app.MaximalSPLTab);
            xlabel(app.MaxSPLplotXmax, 'X')
            ylabel(app.MaxSPLplotXmax, 'Y')
            app.AdjustPanelMaxSPL = uipanel(app.MaximalSPLTab);
            app.AdjustPanelMaxSPL.Title = 'Adjust';
            app.fbHzSpinnerLabel = uilabel(app.AdjustPanelMaxSPL);
            app.fbHzSpinnerLabel.Text = 'fb (Hz)';
            app.f_bMaxSPL = uispinner(app.AdjustPanelMaxSPL);
            app.f_bMaxSPL.ValueDisplayFormat = '%g';
            app.f_bMaxSPL.ValueChangedFcn = createCallbackFcn(app, @f_bMaxSPLValueChanged, true);
            app.VblitersLabel_2 = uilabel(app.AdjustPanelMaxSPL);
            app.VblitersLabel_2.Text = 'Vb (liters)';
            app.VbMaxSPL = uispinner(app.AdjustPanelMaxSPL);
            app.VbMaxSPL.ValueDisplayFormat = '%g';
            app.VbMaxSPL.ValueChangedFcn = createCallbackFcn(app, @VbMaxSPLValueChanged, true);
            app.SPLmaxdBLabel = uilabel(app.AdjustPanelMaxSPL);
            app.SPLmaxdBLabel.Text = 'SPLmax (dB)';
            app.SPLMaxSPL = uispinner(app.AdjustPanelMaxSPL);
            app.SPLMaxSPL.ValueDisplayFormat = '%g';
            app.SPLMaxSPL.ValueChangedFcn = createCallbackFcn(app, @SPLMaxSPLValueChanged, true);
            app.ResetButtonMaxSPL = uibutton(app.AdjustPanelMaxSPL, 'push');
            app.ResetButtonMaxSPL.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonMaxSPLPushed, true);
            app.ResetButtonMaxSPL.Text = 'Reset';
            app.Minimalf3Tab = uitab(app.TabGroup2);
            app.Minimalf3Tab.Title = 'Minimal f3';
            app.Minf3plotSPL = uiaxes(app.Minimalf3Tab);
            title(app.Minf3plotSPL, 'Title')
            xlabel(app.Minf3plotSPL, 'X')
            ylabel(app.Minf3plotSPL, 'Y')
            app.Minf3plotXmax = uiaxes(app.Minimalf3Tab);
            xlabel(app.Minf3plotXmax, 'X')
            ylabel(app.Minf3plotXmax, 'Y')
            app.AdjustPanelMinf3 = uipanel(app.Minimalf3Tab);
            app.AdjustPanelMinf3.Title = 'Adjust';
            app.fbHzSpinnerLabel_2 = uilabel(app.AdjustPanelMinf3);
            app.fbHzSpinnerLabel_2.Text = 'fb (Hz)';
            app.f_bMinf3 = uispinner(app.AdjustPanelMinf3);
            app.f_bMinf3.ValueDisplayFormat = '%g';
            app.f_bMinf3.ValueChangedFcn = createCallbackFcn(app, @f_bMinf3ValueChanged, true);
            app.VblitersLabel_3 = uilabel(app.AdjustPanelMinf3);
            app.VblitersLabel_3.Text = 'Vb (liters)';
            app.VbMinf3 = uispinner(app.AdjustPanelMinf3);
            app.VbMinf3.ValueDisplayFormat = '%g';
            app.VbMinf3.ValueChangedFcn = createCallbackFcn(app, @VbMinf3ValueChanged, true);
            app.SPLmaxdBLabel_2 = uilabel(app.AdjustPanelMinf3);
            app.SPLmaxdBLabel_2.Text = 'SPLmax (dB)';
            app.SPLMinf3 = uispinner(app.AdjustPanelMinf3);
            app.SPLMinf3.ValueDisplayFormat = '%g';
            app.SPLMinf3.ValueChangedFcn = createCallbackFcn(app, @SPLMinf3ValueChanged, true);
            app.ResetButtonMinf3 = uibutton(app.AdjustPanelMinf3, 'push');
            app.ResetButtonMinf3.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonMinf3Pushed, true);
            app.ResetButtonMinf3.Text = 'Reset';
            app.MinimalVbTab = uitab(app.TabGroup2);
            app.MinimalVbTab.Title = 'Minimal Vb';
            app.MinVbplotSPL = uiaxes(app.MinimalVbTab);
            title(app.MinVbplotSPL, 'Title')
            xlabel(app.MinVbplotSPL, 'X')
            ylabel(app.MinVbplotSPL, 'Y')
            app.MinVbplotXmax = uiaxes(app.MinimalVbTab);
            xlabel(app.MinVbplotXmax, 'X')
            ylabel(app.MinVbplotXmax, 'Y')
            app.AdjustPanelMaxSPL_3 = uipanel(app.MinimalVbTab);
            app.AdjustPanelMaxSPL_3.Title = 'Adjust';
            app.fbHzSpinnerLabel_3 = uilabel(app.AdjustPanelMaxSPL_3);
            app.fbHzSpinnerLabel_3.Text = 'fb (Hz)';
            app.f_bMinVb = uispinner(app.AdjustPanelMaxSPL_3);
            app.f_bMinVb.ValueDisplayFormat = '%g';
            app.f_bMinVb.ValueChangedFcn = createCallbackFcn(app, @f_bMinVbValueChanged, true);
            app.VblitersLabel_4 = uilabel(app.AdjustPanelMaxSPL_3);
            app.VblitersLabel_4.Text = 'Vb (liters)';
            app.VbMinVb = uispinner(app.AdjustPanelMaxSPL_3);
            app.VbMinVb.ValueDisplayFormat = '%g';
            app.VbMinVb.ValueChangedFcn = createCallbackFcn(app, @VbMinVbValueChanged, true);
            app.SPLmaxdBLabel_3 = uilabel(app.AdjustPanelMaxSPL_3);
            app.SPLmaxdBLabel_3.Text = 'SPLmax (dB)';
            app.SPLMinVb = uispinner(app.AdjustPanelMaxSPL_3);
            app.SPLMinVb.ValueDisplayFormat = '%g';
            app.SPLMinVb.ValueChangedFcn = createCallbackFcn(app, @SPLMinVbValueChanged, true);
            app.ResetButtonMinVb = uibutton(app.AdjustPanelMaxSPL_3, 'push');
            app.ResetButtonMinVb.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonMinVbPushed, true);
            app.ResetButtonMinVb.Text = 'Reset';
            app.ShortestPortTab = uitab(app.TabGroup2);
            app.ShortestPortTab.Title = 'Shortest Port';
            app.MinPortplotSPL = uiaxes(app.ShortestPortTab);
            title(app.MinPortplotSPL, 'Title')
            xlabel(app.MinPortplotSPL, 'X')
            ylabel(app.MinPortplotSPL, 'Y')
            app.MinPortplotXmax = uiaxes(app.ShortestPortTab);
            xlabel(app.MinPortplotXmax, 'X')
            ylabel(app.MinPortplotXmax, 'Y')
            app.AdjustPanelMinPort = uipanel(app.ShortestPortTab);
            app.AdjustPanelMinPort.Title = 'Adjust';
            app.fbHzSpinnerLabel_4 = uilabel(app.AdjustPanelMinPort);
            app.fbHzSpinnerLabel_4.Text = 'fb (Hz)';
            app.f_bMinPort = uispinner(app.AdjustPanelMinPort);
            app.f_bMinPort.ValueDisplayFormat = '%g';
            app.f_bMinPort.ValueChangedFcn = createCallbackFcn(app, @f_bMinPortValueChanged, true);
            app.VblitersLabel_5 = uilabel(app.AdjustPanelMinPort);
            app.VblitersLabel_5.Text = 'Vb (liters)';
            app.VbMinPort = uispinner(app.AdjustPanelMinPort);
            app.VbMinPort.ValueDisplayFormat = '%g';
            app.VbMinPort.ValueChangedFcn = createCallbackFcn(app, @VbMinPortValueChanged, true);
            app.SPLmaxdBLabel_4 = uilabel(app.AdjustPanelMinPort);
            app.SPLmaxdBLabel_4.Text = 'SPLmax (dB)';
            app.SPLMinPort = uispinner(app.AdjustPanelMinPort);
            app.SPLMinPort.ValueDisplayFormat = '%g';
            app.SPLMinPort.ValueChangedFcn = createCallbackFcn(app, @SPLMinPortValueChanged, true);
            app.ResetButtonMinPort = uibutton(app.AdjustPanelMinPort, 'push');
            app.ResetButtonMinPort.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonMinPortPushed, true);
            app.ResetButtonMinPort.Text = 'Reset';
            app.ConstantsPanel_2 = uipanel(app.DriverselectionfromdatabaseTab);
            app.ConstantsPanel_2.Title = 'Constants';
            app.Airdensitykgm3Label_2 = uilabel(app.ConstantsPanel_2);
            app.Airdensitykgm3Label_2.Text = 'Air density (kg/m3)';
            app.rhofield_2 = uieditfield(app.ConstantsPanel_2, 'numeric');
            app.rhofield_2.Limits = [1 Inf];
            app.rhofield_2.ValueDisplayFormat = '%.2f';
            app.rhofield_2.ValueChangedFcn = createCallbackFcn(app, @rhofield_2ValueChanged, true);
            app.rhofield_2.Value = 1.18;
            app.SoundspeedmsLabel_2 = uilabel(app.ConstantsPanel_2);
            app.SoundspeedmsLabel_2.Text = 'Sound speed (m/s)';
            app.cfield_2 = uieditfield(app.ConstantsPanel_2, 'numeric');
            app.cfield_2.Limits = [1 Inf];
            app.cfield_2.ValueDisplayFormat = '%.1f';
            app.cfield_2.ValueChangedFcn = createCallbackFcn(app, @cfield_2ValueChanged, true);
            app.cfield_2.Value = 345;
            app.ProcessedspeakersLabel = uilabel(app.DriverselectionfromdatabaseTab);
            app.ProcessedspeakersLabel.Text = 'Processed speakers';
            app.BarraCarga = uigauge(app.DriverselectionfromdatabaseTab, 'semicircular');
            app.DefinitionofparametersforcustomdriverdesignTab = uitab(app.TabGroup);
            app.DefinitionofparametersforcustomdriverdesignTab.Title = 'Definition of parameters for custom driver design';
            app.RequiredparametersPanel = uipanel(app.DefinitionofparametersforcustomdriverdesignTab);
            app.RequiredparametersPanel.Title = 'Required parameters';
            app.VblitersLabel_6 = uilabel(app.RequiredparametersPanel);
            app.VblitersLabel_6.Text = 'Vb (liters)';
            app.VbCustom = uieditfield(app.RequiredparametersPanel, 'numeric');
            app.VbCustom.Limits = [0.01 Inf];
            app.VbCustom.ValueDisplayFormat = '%g';
            app.VbCustom.ValueChangedFcn = createCallbackFcn(app, @VbCustomValueChanged, true);
            app.VbCustom.Value = 30;
            app.f3HzLabel = uilabel(app.RequiredparametersPanel);
            app.f3HzLabel.Text = 'f3 (Hz)';
            app.f3Custom = uieditfield(app.RequiredparametersPanel, 'numeric');
            app.f3Custom.Limits = [0.1 Inf];
            app.f3Custom.ValueDisplayFormat = '%g';
            app.f3Custom.ValueChangedFcn = createCallbackFcn(app, @f3CustomValueChanged, true);
            app.f3Custom.Value = 60;
            app.SPLmaxdBLabel_5 = uilabel(app.RequiredparametersPanel);
            app.SPLmaxdBLabel_5.Text = 'SPLmax (dB)';
            app.SPLmaxCustom = uieditfield(app.RequiredparametersPanel, 'numeric');
            app.SPLmaxCustom.Limits = [0 194];
            app.SPLmaxCustom.ValueDisplayFormat = '%g';
            app.SPLmaxCustom.ValueChangedFcn = createCallbackFcn(app, @SPLmaxCustomValueChanged, true);
            app.SPLmaxCustom.Value = 110;
            app.SizeinchesEditField_3Label = uilabel(app.RequiredparametersPanel);
            app.SizeinchesEditField_3Label.Text = 'Size (inches)';
            app.SizeCustom = uieditfield(app.RequiredparametersPanel, 'numeric');
            app.SizeCustom.Limits = [0.1 Inf];
            app.SizeCustom.ValueDisplayFormat = '%g';
            app.SizeCustom.ValueChangedFcn = createCallbackFcn(app, @SizeCustomValueChanged, true);
            app.SizeCustom.Value = 15;
            app.QLLabel = uilabel(app.RequiredparametersPanel);
            app.QLLabel.Text = 'QL';
            app.QL_Custom = uieditfield(app.RequiredparametersPanel, 'numeric');
            app.QL_Custom.Limits = [0.001 Inf];
            app.QL_Custom.ValueDisplayFormat = '%g';
            app.QL_Custom.ValueChangedFcn = createCallbackFcn(app, @QL_CustomValueChanged, true);
            app.QL_Custom.Value = 7;
            app.ReohmEditFieldLabel = uilabel(app.RequiredparametersPanel);
            app.ReohmEditFieldLabel.Text = 'Re (ohm)';
            app.ReCustom = uieditfield(app.RequiredparametersPanel, 'numeric');
            app.ReCustom.Limits = [0.01 Inf];
            app.ReCustom.ValueDisplayFormat = '%g';
            app.ReCustom.ValueChangedFcn = createCallbackFcn(app, @ReCustomValueChanged, true);
            app.ReCustom.Value = 6;
            app.AlignmenttypeDropDownLabel = uilabel(app.RequiredparametersPanel);
            app.AlignmenttypeDropDownLabel.Text = 'Alignment type';
            app.AlignmenttypeDropDown = uidropdown(app.RequiredparametersPanel);
            app.AlignmenttypeDropDown.Items = {'B4', 'QB3', 'C4'};
            app.AlignmenttypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AlignmenttypeDropDownValueChanged, true);
            app.AlignmenttypeDropDown.Value = 'B4';
            app.AlignCoefCustom = uieditfield(app.RequiredparametersPanel, 'numeric');
            app.AlignCoefCustom.Limits = [0 Inf];
            app.AlignCoefCustom.ValueDisplayFormat = '%g';
            app.AlignCoefCustom.ValueChangedFcn = createCallbackFcn(app, @AlignCoefCustomValueChanged, true);
            app.AlignCoefCustomLabel = uilabel(app.RequiredparametersPanel);
            app.AlignCoefCustomLabel.Text = 'B | Ripple';
            app.ConstantsPanel_3 = uipanel(app.DefinitionofparametersforcustomdriverdesignTab);
            app.ConstantsPanel_3.Title = 'Constants';
            app.Airdensitykgm3Label_3 = uilabel(app.ConstantsPanel_3);
            app.Airdensitykgm3Label_3.Text = 'Air density (kg/m3)';
            app.rhofield_3 = uieditfield(app.ConstantsPanel_3, 'numeric');
            app.rhofield_3.Limits = [1 Inf];
            app.rhofield_3.ValueDisplayFormat = '%.2f';
            app.rhofield_3.ValueChangedFcn = createCallbackFcn(app, @rhofield_3ValueChanged, true);
            app.rhofield_3.Value = 1.18;
            app.SoundspeedmsLabel_3 = uilabel(app.ConstantsPanel_3);
            app.SoundspeedmsLabel_3.Text = 'Sound speed (m/s)';
            app.cfield_3 = uieditfield(app.ConstantsPanel_3, 'numeric');
            app.cfield_3.Limits = [1 Inf];
            app.cfield_3.ValueDisplayFormat = '%.1f';
            app.cfield_3.ValueChangedFcn = createCallbackFcn(app, @cfield_3ValueChanged, true);
            app.cfield_3.Value = 345;
            app.plotXmaxCustom = uiaxes(app.DefinitionofparametersforcustomdriverdesignTab);
            xlabel(app.plotXmaxCustom, 'X')
            ylabel(app.plotXmaxCustom, 'Y')
            app.plotSPLCustom = uiaxes(app.DefinitionofparametersforcustomdriverdesignTab);
            title(app.plotSPLCustom, 'Custom Speaker')
            xlabel(app.plotSPLCustom, 'X')
            ylabel(app.plotSPLCustom, 'Y')
            app.ParametersresultsCustom = uipanel(app.DefinitionofparametersforcustomdriverdesignTab);
            app.ParametersresultsCustom.Title = 'Parameters results';
            app.ParamResultsCustom = uitable(app.ParametersresultsCustom);
            app.ParamResultsCustom.ColumnName = {'Values'; 'Units'};
            app.ParamResultsCustom.RowName = {'Qts'; 'Qms'; 'Qes'; 'fs'; 'Pe (program)'; 'Pe (AES)'; 'Eff'; 'SPL_1W'; 'SPL_max'; 'Vas'; 'Sd'; 'Cms'; 'Mms'; 'Bl'; 'Re'; 'Rms'; 'Xmax'; 'D_p'; 'L_p'};
            app.WarningsTextArea = uipanel(app.LoVEBASSUIFigure);
            app.WarningsTextArea.Title = 'Warnings';
            app.WarningsTexto = uitextarea(app.WarningsTextArea);
            app.WarningsTexto.Editable = 'off';
            % Show the figure after all components are created
            app.LoVEBASSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoVEBASS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoVEBASSUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoVEBASSUIFigure)
        end
    end
end